classdef Maquina02MNED < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        FileMenu                     matlab.ui.container.Menu
        SairMenu                     matlab.ui.container.Menu
        RelatrioMenu                 matlab.ui.container.Menu
        CrditosMenu                  matlab.ui.container.Menu
        SobreosAutoresMenu           matlab.ui.container.Menu
        PaginadaDisciplinaMenu       matlab.ui.container.Menu
        ExtrasMenu                   matlab.ui.container.Menu
        GrficoParaImagemMenu         matlab.ui.container.Menu
        TabelaParaExcellMenu         matlab.ui.container.Menu
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        OcultarLegendaCheckBox       matlab.ui.control.CheckBox
        GrelhaCheckBox               matlab.ui.control.CheckBox
        ResetButton                  matlab.ui.control.Button
        ExecutarButton               matlab.ui.control.Button
        MetodosNumericosButtonGroup  matlab.ui.container.ButtonGroup
        TodososMetodosButton         matlab.ui.control.ToggleButton
        OutroButton                  matlab.ui.control.ToggleButton
        ODE45Button                  matlab.ui.control.ToggleButton
        RK4Button                    matlab.ui.control.ToggleButton
        RK2Button                    matlab.ui.control.ToggleButton
        EulerMButton                 matlab.ui.control.ToggleButton
        EulerButton                  matlab.ui.control.ToggleButton
        DadosdoPVIPanel              matlab.ui.container.Panel
        eY0                          matlab.ui.control.NumericEditField
        aEditFieldLabel_3            matlab.ui.control.Label
        nSpinner                     matlab.ui.control.Spinner
        nSpinnerLabel                matlab.ui.control.Label
        aEditFieldLabel_2            matlab.ui.control.Label
        eB                           matlab.ui.control.NumericEditField
        eA                           matlab.ui.control.NumericEditField
        aEditFieldLabel              matlab.ui.control.Label
        eF                           matlab.ui.control.EditField
        ftyEditFieldLabel            matlab.ui.control.Label
        RightPanel                   matlab.ui.container.Panel
        GridLayout2                  matlab.ui.container.GridLayout
        UITable                      matlab.ui.control.Table
        UIAxes                       matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            ExecutarButtonPushed(app,[])
        end

        % Callback function: ExecutarButton, MetodosNumericosButtonGroup, 
        % ...and 1 other component
        function ExecutarButtonPushed(app, event)
            syms y(t)
            strF = app.eF.Value;
            f=@(t,y) eval(vectorize(strF));
            a = app.eA.Value;
            b = app.eB.Value;
            n = app.nSpinner.Value;
            y0= app.eY0.Value;

            erroFuncao = MException("MATLAB:Executar:badF","Introduza uma função em t e y");
            erroEntrada = MException("Matlab:bAtualizar:badB","Introduza um b > a" );

            MNumericoSel = app.MetodosNumericosButtonGroup.SelectedObject.Text;

            try
                try
                    z = f(t,y);
                    app.eF.BackgroundColor = "w";
                catch
                    app.eF.BackgroundColor = [1,0,0];
                    throw(erroFuncao)
                end

                if ~(isreal(b) && b > a)
                    app.eB.BackgroundColor = "r";
                    throw(erroEntrada)
                else
                    app.eB.BackgroundColor = "w";

                end

                sExata = dsolve(diff(y,t)==f(t,y),y(a)==y0);
                g =@(t) eval(vectorize(char(sExata)));
                
                [t,yEuler] = NEuler(f,a,b,n,y0);
                yEulerM = NEulerM(f,a,b,n,y0);
                [~,yRK2] = NRK2(f,a,b,n,y0);
                [~,yRK4] = NRK4(f,a,b,n,y0);
                [~,yODE45] = ODE45(f,a,b,n,y0);
                yHeun = Heun(f,a,b,n,y0);
                yHeun = yHeun(1:end,1:end-1);
                % Chamar as outras funções/métodos
                
                yExata = g(t);
                erroEuler = abs(yExata-yEuler);
                erroEulerM = abs(yExata-yEulerM);
                erroRK2 = abs(yExata-yRK2);
                erroRK4 = abs(yExata-yRK4);
                erroODE45 = abs(yExata-yODE45);
                erroHeun = abs(yExata-yHeun);
                % mais erros

                plot(app.UIAxes,t,yExata,"-ro")
                hold(app.UIAxes,"on")

                switch MNumericoSel
                    case "Euler"
                        plot(app.UIAxes,t,yEuler,"-g*")
                        legendaGrafico = ["Exata","Euler"];
                        A = [t.',yExata.',yEuler.',erroEuler.'];
                        nomeColunas = ["t","yExata","yEuler","erroEuler"];
                    case "Euler M"
                        plot(app.UIAxes,t,yEulerM,"-C*")
                        legendaGrafico = ["Exata","EulerM"];
                        A = [t.',yExata.',yEulerM.',erroEulerM.'];
                        nomeColunas = ["t","yExata","yEuler","erroEulerM"];
                    case "RK2"
                        plot(app.UIAxes,t,yRK2,"-b+")
                        legendaGrafico = ["Exata","RK2"];
                        A = [t.',yExata.',yRK2.',erroRK2.'];
                        nomeColunas = ["t","yExata","yRK2","erroRK2"];
                    case "RK4"
                        plot(app.UIAxes,t,yRK4,"-k+")
                        legendaGrafico = ["Exata","RK4"];
                        A = [t.',yExata.',yRK4.',erroRK4.'];
                        nomeColunas = ["t","yExata","yRK4","erroRK4"];
                    case "ODE45"
                        plot(app.UIAxes,t,yODE45,"-B+")
                        legendaGrafico = ["Exata","ODE45"];
                        A = [t.',yExata.',yODE45.',erroODE45.'];
                        nomeColunas = ["t","yExata","yODE45","erroODE45"];
                    case "Outro"
                        plot(app.UIAxes,t,yHeun,"-m+")
                        legendaGrafico = ["Exata","Heun"];
                        A = [t.',yExata.',yHeun.',erroHeun.'];
                        nomeColunas = ["t","yExata","yHeun","erroHeun"];
                    case "Todos os Métodos"
                        plot(app.UIAxes,t,yEuler,"-g*")
                        plot(app.UIAxes,t,yRK2,"-b+")
                        plot(app.UIAxes,t,yRK4,"-k+")
                        plot(app.UIAxes,t,yODE45,"-y+")
                        plot(app.UIAxes,t,yHeun,"-m+")
                        legendaGrafico = ["Exata","Euler","RK2","RK4","ODE45","Heun"];
                        
                        A = [t.',yExata.',yEuler.',yRK2.',yRK4.',yODE45.',...
                            yHeun.',erroEuler.',erroRK2.',erroRK4.',...
                            erroODE45.',erroHeun.'];
                        
                        nomeColunas = ["t","yExata","yEuler","yRK2","yRK4",...
                            "yODE45","yHeun","erroEuler","erroRK2",...
                            "erroRK4","erroODE45","erroHeun"];                        

                end
                 
                legend(app.UIAxes,legendaGrafico) 
                hold(app.UIAxes,"off")

                tabelaResultados = array2table(A);
                app.UITable.Data = tabelaResultados;
                app.UITable.ColumnName = nomeColunas;
            
            catch Me
                errordlg(Me.message,"ERRO","modal")
            end

        end

        % Menu selected function: SairMenu
        function SairMenuSelected(app, event)
            sn = questdlg("Deseja Mesmo Sair?","SAIR","Sim","Não","Não");
            if strcmp(sn,"Sim") == 1 
                delete(app.UIFigure);
            end
        end

        % Menu selected function: SobreosAutoresMenu
        function SobreosAutoresMenuSelected(app, event)
            Autores
        end

        % Menu selected function: RelatrioMenu
        function RelatrioMenuSelected(app, event)
            open('Ricardo_Duarte-2022137878_Pedro_Jacome-2022137038_Guilherme_Domingos-2022136668.pdf');
        end

        % Menu selected function: PaginadaDisciplinaMenu
        function PaginadaDisciplinaMenuSelected(app, event)
            web("https://moodle.isec.pt/moodle/course/view.php?id=20386");
        end

        % Value changed function: GrelhaCheckBox
        function GrelhaCheckBoxValueChanged(app, event)
            value = app.GrelhaCheckBox.Value;
            if value 
                grid(app.UIAxes,"on");
            else
                grid(app.UIAxes,"off");
            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            % botao reset
            % questiona o  user se pretende mesmo dar reset

            choice = questdlg('Tem certeza que quer dar reset?', ...
                'Reset', ...
                'Sim','Não','Não');
            switch choice
                case 'Sim'

                    app.eF.Value = 'y+exp(3*t)';%apagar a funcao (string)
                    app.nSpinner.Value = 2; %valor numerico
                    app.eA.Value = 0;
                    app.eY0.Value = 2;
                    app.eB.Value = 1.5;
                    app.MetodosNumericosButtonGroup.SelectedObject = app.EulerButton; %botão default - método de Euler
                    ExecutarButtonPushed(app, event); %chama a função atualiza

                case 'Não'
                    % Não faz reset
            end
        end

        % Menu selected function: GrficoParaImagemMenu
        function GrficoParaImagemMenuSelected(app, event)
            % Receber o nome do ficheiro
            [file, path] = uiputfile({'.png','PNG files (.png)'},'Save As');
            if file == 0
                return;
            end
            filename = fullfile(path, file);

            % Tira uma print ao grafico
            exportgraphics(app.UIAxes, filename, 'Resolution', 300);
            msgbox(['UI exported to ', filename]);
        end

        % Menu selected function: TabelaParaExcellMenu
        function TabelaParaExcellMenuSelected(app, event)
            % Obter os dados da tabela que deseja exportar
            dados = app.UITable.Data;
            colunas = app.UITable.ColumnName;

            % Verificar se há tabelas aninhadas e convertê-las em células
            dados = convertNestedTablesToCells(dados);

            % Criar uma tabela a partir dos dados e nomes das colunas
            tabela = array2table(dados, 'VariableNames', colunas);

            % Especificar o nome do arquivo Excel de destino
            nomeFicheiro = 'dados.xlsx';

            % Salvar a tabela no arquivo Excel
            writetable(tabela, nomeFicheiro);

            % Exibir uma mensagem de confirmação
            msgbox('Tabela exportada com sucesso para o Excel.', 'Exportação Concluída');

            % Função para converter tabelas aninhadas em células
            function data = convertNestedTablesToCells(data)
                if istable(data)
                    data = table2cell(data);
                elseif iscell(data)
                    for i = 1:numel(data)
                        data{i} = convertNestedTablesToCells(data{i});
                    end
                end
            end
            winopen(nomeFicheiro);
        end

        % Value changed function: OcultarLegendaCheckBox
        function OcultarLegendaCheckBoxValueChanged(app, event)
            value = app.OcultarLegendaCheckBox.Value;
            if value
                legend(app.UIAxes,"off");
            else
                legend(app.UIAxes);
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {459, 459};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.SairMenu = uimenu(app.FileMenu);
            app.SairMenu.MenuSelectedFcn = createCallbackFcn(app, @SairMenuSelected, true);
            app.SairMenu.Text = 'Sair';
            app.RelatrioMenu = uimenu(app.FileMenu);
            app.RelatrioMenu.MenuSelectedFcn = createCallbackFcn(app, @RelatrioMenuSelected, true);
            app.RelatrioMenu.Text = 'Relatório';
            app.CrditosMenu = uimenu(app.UIFigure);
            app.CrditosMenu.Text = 'Créditos';
            app.SobreosAutoresMenu = uimenu(app.CrditosMenu);
            app.SobreosAutoresMenu.MenuSelectedFcn = createCallbackFcn(app, @SobreosAutoresMenuSelected, true);
            app.SobreosAutoresMenu.Text = 'Sobre os Autores';
            app.PaginadaDisciplinaMenu = uimenu(app.CrditosMenu);
            app.PaginadaDisciplinaMenu.MenuSelectedFcn = createCallbackFcn(app, @PaginadaDisciplinaMenuSelected, true);
            app.PaginadaDisciplinaMenu.Text = 'Pagina da Disciplina';
            app.ExtrasMenu = uimenu(app.UIFigure);
            app.ExtrasMenu.Text = 'Extras';
            app.GrficoParaImagemMenu = uimenu(app.ExtrasMenu);
            app.GrficoParaImagemMenu.MenuSelectedFcn = createCallbackFcn(app, @GrficoParaImagemMenuSelected, true);
            app.GrficoParaImagemMenu.Text = 'Gráfico Para Imagem';
            app.TabelaParaExcellMenu = uimenu(app.ExtrasMenu);
            app.TabelaParaExcellMenu.MenuSelectedFcn = createCallbackFcn(app, @TabelaParaExcellMenuSelected, true);
            app.TabelaParaExcellMenu.Text = 'Tabela Para Excell';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.DadosdoPVIPanel = uipanel(app.LeftPanel);
            app.DadosdoPVIPanel.Title = 'Dados do PVI';
            app.ftyEditFieldLabel = uilabel(app.DadosdoPVIPanel);
            app.ftyEditFieldLabel.Text = 'f(t,y) =';
            app.eF = uieditfield(app.DadosdoPVIPanel, 'text');
            app.eF.Value = 'y+exp(3*t)';
            app.aEditFieldLabel = uilabel(app.DadosdoPVIPanel);
            app.aEditFieldLabel.Text = 'a';
            app.eA = uieditfield(app.DadosdoPVIPanel, 'numeric');
            app.eB = uieditfield(app.DadosdoPVIPanel, 'numeric');
            app.eB.Value = 1.5;
            app.aEditFieldLabel_2 = uilabel(app.DadosdoPVIPanel);
            app.aEditFieldLabel_2.Text = 'b';
            app.nSpinnerLabel = uilabel(app.DadosdoPVIPanel);
            app.nSpinnerLabel.Text = 'n';
            app.nSpinner = uispinner(app.DadosdoPVIPanel);
            app.nSpinner.Limits = [1 Inf];
            app.nSpinner.ValueDisplayFormat = '%.0f';
            app.nSpinner.ValueChangedFcn = createCallbackFcn(app, @ExecutarButtonPushed, true);
            app.nSpinner.Value = 2;
            app.aEditFieldLabel_3 = uilabel(app.DadosdoPVIPanel);
            app.aEditFieldLabel_3.Text = 'y0';
            app.eY0 = uieditfield(app.DadosdoPVIPanel, 'numeric');
            app.eY0.Value = 2;
            app.MetodosNumericosButtonGroup = uibuttongroup(app.LeftPanel);
            app.MetodosNumericosButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ExecutarButtonPushed, true);
            app.MetodosNumericosButtonGroup.Title = 'Métodos Numéricos';
            app.EulerButton = uitogglebutton(app.MetodosNumericosButtonGroup);
            app.EulerButton.Text = 'Euler';
            app.EulerButton.Value = true;
            app.EulerMButton = uitogglebutton(app.MetodosNumericosButtonGroup);
            app.EulerMButton.Text = 'Euler M';
            app.RK2Button = uitogglebutton(app.MetodosNumericosButtonGroup);
            app.RK2Button.Text = 'RK2';
            app.RK4Button = uitogglebutton(app.MetodosNumericosButtonGroup);
            app.RK4Button.Text = 'RK4';
            app.ODE45Button = uitogglebutton(app.MetodosNumericosButtonGroup);
            app.ODE45Button.Text = 'ODE45';
            app.OutroButton = uitogglebutton(app.MetodosNumericosButtonGroup);
            app.OutroButton.Text = 'Outro';
            app.TodososMetodosButton = uitogglebutton(app.MetodosNumericosButtonGroup);
            app.TodososMetodosButton.Text = 'Todos os Métodos';
            app.ExecutarButton = uibutton(app.LeftPanel, 'push');
            app.ExecutarButton.ButtonPushedFcn = createCallbackFcn(app, @ExecutarButtonPushed, true);
            app.ExecutarButton.Text = 'Executar';
            app.ResetButton = uibutton(app.LeftPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.GrelhaCheckBox = uicheckbox(app.LeftPanel);
            app.GrelhaCheckBox.ValueChangedFcn = createCallbackFcn(app, @GrelhaCheckBoxValueChanged, true);
            app.GrelhaCheckBox.Text = 'Grelha';
            app.OcultarLegendaCheckBox = uicheckbox(app.LeftPanel);
            app.OcultarLegendaCheckBox.ValueChangedFcn = createCallbackFcn(app, @OcultarLegendaCheckBoxValueChanged, true);
            app.OcultarLegendaCheckBox.Text = 'Ocultar Legenda';
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.RightPanel);
            app.UIAxes = uiaxes(app.GridLayout2);
            xlabel(app.UIAxes, 't')
            ylabel(app.UIAxes, 'y')
            app.UITable = uitable(app.GridLayout2);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Maquina02MNED

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end