classdef Maquina01MNSED < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        FileMenu                     matlab.ui.container.Menu
        RelatorioMenu                matlab.ui.container.Menu
        PaginaWebMenu                matlab.ui.container.Menu
        SairMenu                     matlab.ui.container.Menu
        SobreMenu                    matlab.ui.container.Menu
        AutoresMenu                  matlab.ui.container.Menu
        ExportarMenu                 matlab.ui.container.Menu
        GraficoParaImagemMenu        matlab.ui.container.Menu
        TabelaParaExcellMenu         matlab.ui.container.Menu
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        OcultarLegendaCheckBox       matlab.ui.control.CheckBox
        ProblemasDropDown            matlab.ui.control.DropDown
        ProblemasdinmicosLabel       matlab.ui.control.Label
        GrelhaCheckBox               matlab.ui.control.CheckBox
        ResetButton                  matlab.ui.control.Button
        ExecutarButton               matlab.ui.control.Button
        MetodosNumericosButtonGroup  matlab.ui.container.ButtonGroup
        TodososMetodosButton         matlab.ui.control.ToggleButton
        RK4Button                    matlab.ui.control.ToggleButton
        RK2Button                    matlab.ui.control.ToggleButton
        EulerMButton                 matlab.ui.control.ToggleButton
        EulerButton                  matlab.ui.control.ToggleButton
        DadosdoPVIPanel              matlab.ui.container.Panel
        eV0                          matlab.ui.control.NumericEditField
        aEditFieldLabel_4            matlab.ui.control.Label
        eG                           matlab.ui.control.EditField
        gtuvLabel                    matlab.ui.control.Label
        eU0                          matlab.ui.control.NumericEditField
        aEditFieldLabel_3            matlab.ui.control.Label
        nSpinner                     matlab.ui.control.Spinner
        nSpinnerLabel                matlab.ui.control.Label
        aEditFieldLabel_2            matlab.ui.control.Label
        eB                           matlab.ui.control.NumericEditField
        eA                           matlab.ui.control.NumericEditField
        aEditFieldLabel              matlab.ui.control.Label
        eF                           matlab.ui.control.EditField
        ftuvLabel                    matlab.ui.control.Label
        RightPanel                   matlab.ui.container.Panel
        GridLayout2                  matlab.ui.container.GridLayout
        UITable                      matlab.ui.control.Table
        UIAxes                       matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    methods (Access = public)
        
        function [t,u,v] = NRK2SED(~,f,g,a,b,n,u0,v0)
            %UNTITLED Summary of this function goes here
            %   17/04/2024  Arménio Correia     armenioc@isec.pt
            h = (b-a)/n;
            t = a:h:b;
            u = zeros(1,n+1); v = zeros(1,n+1);
            u(1) = u0; v(1) = v0;
            for i = 1:n
                k1u = h*f(t(i),u(i),v(i));
                k1v = h*g(t(i),u(i),v(i));
                k2u = h*f(t(i+1),u(i)+k1u,v(i)+k1v);
                k2v = h*g(t(i+1),u(i)+k1u,v(i)+k1v);
                u(i+1) = u(i)+(k1u+k2u)/2;
                v(i+1) = v(i)+(k1v+k2v)/2;
            end            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            ExecutarButtonPushed(app,[])
        end

        % Callback function: ExecutarButton, MetodosNumericosButtonGroup, 
        % ...and 1 other component
        function ExecutarButtonPushed(app, event)
            syms u(t) v(t)
            strF = app.eF.Value;
            f=@(t,u,v) eval(vectorize(strF));
            strG = app.eG.Value;
            g=@(t,u,v) eval(vectorize(strG));           
            a = app.eA.Value; 
            b = app.eB.Value; 
            n= app.nSpinner.Value;
            u0 = app.eU0.Value; 
            v0 = app.eV0.Value;
            

            erroGtuv = MException("MATLAB:Executar:badG","Introduza uma função em t, u e v");
            erroB = MException("Matlab:bAtualizar:badB","Introduza um b > a" );
            %outras exceções

            MNumericoSel = app.MetodosNumericosButtonGroup.SelectedObject.Text;
    
            try
                try
                    z = g(t,u,v);
                    app.eG.BackgroundColor = "w";
                catch
                    app.eG.BackgroundColor = "r";
                    throw(erroGtuv)
                end

                if ~(isreal(b) && b > a)
                    app.eB.BackgroundColor = "r";
                    throw(erroB)
                else
                    app.eB.BackgroundColor = "w";
                end


                SED = [diff(u,t)==v,diff(v,t)==-16*u];
                if(app.ProblemasDropDown.Value == '2')
                    SED = [diff(u,t)==v,diff(v,t)==-10*v-25*u];
                end

                if(app.ProblemasDropDown.Value == '4')
                    SED = [diff(u,t)==v,diff(v,t)==5*v-4*u+2*exp(-t/4)];
                end
                

                ci  = [u(a)==u0,v(a)==v0];
                [uExata,~] = dsolve(SED,ci);
                h =@(t) eval(vectorize(char(uExata)));
                
                [t,uEuler,~] = NEulerSED(f,g,a,b,n,u0,v0);
                [t,uEulerM,~] = NEulerMSED(f,g,a,b,n,u0,v0);
                [~,uRK2,~] = NRK2SED(app,f,g,a,b,n,u0,v0);
                [t,uRK4,~] = NRK4SED(f,g,a,b,n,u0,v0);
                % Chamar as outras funções/métodos
                
                uExata = h(t);
                erroEuler = abs(uExata-uEuler);
                erroEulerM = abs(uExata-uEulerM);
                erroRK2 = abs(uExata-uRK2);
                erroRK4 = abs(uExata-uRK4);
                % mais erros    


                switch MNumericoSel
                    case "Euler"
                        plot(app.UIAxes,t,uEuler,"-g")
                        legendaGrafico = "Euler";
                        A = [uEuler.',erroEuler.'];
                        nomeColunas = ["t","uExata","uEuler","erroEuler"];
                    case "Euler M"
                        plot(app.UIAxes,t,uEulerM,"-g")
                        legendaGrafico = "EulerM";
                        A = [uEulerM.',erroEulerM.'];
                        nomeColunas = ["uEulerM","erroEulerM"];
                    case "RK2"
                        plot(app.UIAxes,t,uRK2,"-g")
                        legendaGrafico = "RK2";
                        A = [uRK2.',erroRK2.'];
                        nomeColunas = ["t","uExata","uRK2","erroRK2"];
                    case "RK4"
                        plot(app.UIAxes,t,uRK4,"-g")
                        A = [uRK4.',erroRK4.'];
                        legendaGrafico = "RK4";
                        nomeColunas = ["RK4","Erro RK4"];
                    case "Todos os Métodos"
                        plot(app.UIAxes,t,uEuler,"-g")
                        hold(app.UIAxes, "on")
                        plot(app.UIAxes,t,uEulerM,"-m")
                        plot(app.UIAxes,t,uRK2,"-b")
                        plot(app.UIAxes,t,uRK4,"-r")
                        legendaGrafico = ["Euler","EulerM","RK2","RK4"];
                        A = [uEuler.',uRK2.',erroEuler.',erroEulerM.',erroRK2.',erroRK4.'];
                        nomeColunas = ["uEuler","uRK2","erroEuler","erroEulerM","erroRK2","erroRK4"];                        
                end
                 
                legend(app.UIAxes,legendaGrafico) 
                
                hold(app.UIAxes,"off")

                tabelaResultados = array2table(A);
                app.UITable.Data = tabelaResultados;
                app.UITable.ColumnName = nomeColunas;
            
            catch Me
                errordlg(Me.message,"ERRO","modal")
            end

        end

        % Value changed function: GrelhaCheckBox
        function GrelhaCheckBoxValueChanged(app, event)
            value = app.GrelhaCheckBox.Value;
            if value
                grid(app.UIAxes,"on")
            else
                grid(app.UIAxes,"off")
            end
        end

        % Menu selected function: SairMenu
        function SairMenuSelected(app, event)
            SN = questdlg("Deseja Sair?","SAIR","Sim","Não","Sim");
            if strcmp(SN, "Sim")
                delete(app.UIFigure)
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            %delete(app)
            SairMenuSelected(app, event)
        end

        % Menu selected function: RelatorioMenu
        function RelatorioMenuSelected(app, event)
            open("Ricardo_Duarte-2022137878_Pedro_Jacome-2022137038_Guilherme_Domingos-2022136668.pdf")
        end

        % Menu selected function: PaginaWebMenu
        function PaginaWebMenuSelected(app, event)
            web("www.isec.pt")
        end

        % Menu selected function: AutoresMenu
        function AutoresMenuSelected(app, event)
            Autores
        end

        % Value changed function: ProblemasDropDown
        function ProblemasDropDownValueChanged(app, event)
            valor = app.ProblemasDropDown.Value;
            switch valor
                case 1 %mola massa s/amortecimento
                    app.eG.Value = "-16*u";
                    a = 0; b = 4; n = 100; u0 = 9; v0 = 0;
                case 2 %mola massa com amortecimento
                    app.eG.Value = "-10*v-25*u";
                    a = 0; b = 2; n = 100; u0 = 0; v0 = -4;
                    
                case 3 %pendulo
                    app.eG.Value = "-0.3*v-sin(u)";
                    a = 0; b = 15; n = 100; u0 = pi/2; v0 = 0;
                    
                case 4 %Circuitos Elétricos
                    app.eG.Value =  "-5*v-4*u+2*exp(-t/4)";
                    a = 0; b = 10; n = 100; u0 = 0.1; v0 = 0;
                case 5 %outro
                    app.eG.Value =  "0";
                    a = 0; b = 10; n = 100; u0 = 0; v0 = 0;
                    cla(app.UIAxes, 'reset'); % limpa gráfico
                    app.UITable.Data = {}; %limpa tabela
                    warndlg('Introduza o problema em g(t,u,v)','Warning');
                
            end
           
            app.eA.Value = a; 
            app.eB.Value = b; 
            app.nSpinner.Value = n;
            app.eU0.Value= u0; 
            app.eV0.Value = v0;
            if ~strcmp(valor, "5") % apenas chamar a função se o valor não for "5"
                ExecutarButtonPushed(app,[])
            end     
        end

        % Value changed function: OcultarLegendaCheckBox
        function OcultarLegendaCheckBoxValueChanged(app, event)
            value = app.OcultarLegendaCheckBox.Value;
            if value
                legend(app.UIAxes,"off");
            else
                legend(app.UIAxes);
            end

        end

        % Menu selected function: GraficoParaImagemMenu
        function GraficoParaImagemMenuSelected(app, event)
            [file, path] = uiputfile({'.png','PNG files (.png)'},'Save As');
            if file == 0
                return;
            end
            filename = fullfile(path, file);
            
            exportgraphics(app.UIAxes, filename, 'Resolution', 300);
            msgbox(['UI exported to ', filename]);
        end

        % Menu selected function: TabelaParaExcellMenu
        function TabelaParaExcellMenuSelected(app, event)
            dados = app.UITable.Data;
            colunas = app.UITable.ColumnName;
            dados = convertNestedTablesToCells(dados);
            tabela = array2table(dados, 'VariableNames', colunas);
            nomeFicheiro = 'dados.xlsx';

            if isfile("dados.xlsx")
                delete("dados.xlsx");
            end

            writetable(tabela, nomeFicheiro);
            msgbox('Tabela exportada com sucesso.', 'Concluído');

            function data = convertNestedTablesToCells(data)
                if istable(data)
                    data = table2cell(data);
                elseif iscell(data)
                    for i = 1:numel(data)
                        data{i} = convertNestedTablesToCells(data{i});
                    end
                end
            end


        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            choice = questdlg('quer dar reset?', ...
                'Confirmação', ...
                'Sim','Não','Não');
            % Verifica qual botão foi pressionado
            switch choice
                case 'Sim'
                    app.eF.Value = 'v';
                    app.eG.Value = '-16*u';
                    app.nSpinner.Value = 100; 
                    app.eA.Value = 0;
                    app.eU0.Value = 9;
                    app.eB.Value = 4;
                    app.eV0.Value = 0;
                    app.MetodosNumericosButtonGroup.SelectedObject = app.EulerButton; 
                    ExecutarButtonPushed(app, event); 

                case 'Não'
                    
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {435, 435};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {223, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.RelatorioMenu = uimenu(app.FileMenu);
            app.RelatorioMenu.MenuSelectedFcn = createCallbackFcn(app, @RelatorioMenuSelected, true);
            app.RelatorioMenu.Text = 'Relatório';
            app.PaginaWebMenu = uimenu(app.FileMenu);
            app.PaginaWebMenu.MenuSelectedFcn = createCallbackFcn(app, @PaginaWebMenuSelected, true);
            app.PaginaWebMenu.Text = 'PáginaWeb';
            app.SairMenu = uimenu(app.FileMenu);
            app.SairMenu.MenuSelectedFcn = createCallbackFcn(app, @SairMenuSelected, true);
            app.SairMenu.Text = 'Sair';
            app.SobreMenu = uimenu(app.UIFigure);
            app.SobreMenu.Text = 'Sobre';
            app.AutoresMenu = uimenu(app.SobreMenu);
            app.AutoresMenu.MenuSelectedFcn = createCallbackFcn(app, @AutoresMenuSelected, true);
            app.AutoresMenu.Text = 'Autores';
            app.ExportarMenu = uimenu(app.UIFigure);
            app.ExportarMenu.Text = 'Exportar';
            app.GraficoParaImagemMenu = uimenu(app.ExportarMenu);
            app.GraficoParaImagemMenu.MenuSelectedFcn = createCallbackFcn(app, @GraficoParaImagemMenuSelected, true);
            app.GraficoParaImagemMenu.Text = 'Grafico Para Imagem';
            app.TabelaParaExcellMenu = uimenu(app.ExportarMenu);
            app.TabelaParaExcellMenu.MenuSelectedFcn = createCallbackFcn(app, @TabelaParaExcellMenuSelected, true);
            app.TabelaParaExcellMenu.Text = 'Tabela Para Excell';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.DadosdoPVIPanel = uipanel(app.LeftPanel);
            app.DadosdoPVIPanel.Title = 'Dados do PVI';
            app.ftuvLabel = uilabel(app.DadosdoPVIPanel);
            app.ftuvLabel.Text = 'f(t,u,v) =';
            app.eF = uieditfield(app.DadosdoPVIPanel, 'text');
            app.eF.Editable = 'off';
            app.eF.Value = 'v';
            app.aEditFieldLabel = uilabel(app.DadosdoPVIPanel);
            app.aEditFieldLabel.Text = 'a';
            app.eA = uieditfield(app.DadosdoPVIPanel, 'numeric');
            app.eA.Value = 1;
            app.eB = uieditfield(app.DadosdoPVIPanel, 'numeric');
            app.eB.Value = 8;
            app.aEditFieldLabel_2 = uilabel(app.DadosdoPVIPanel);
            app.aEditFieldLabel_2.Text = 'b';
            app.nSpinnerLabel = uilabel(app.DadosdoPVIPanel);
            app.nSpinnerLabel.Text = 'n';
            app.nSpinner = uispinner(app.DadosdoPVIPanel);
            app.nSpinner.Limits = [2 Inf];
            app.nSpinner.ValueDisplayFormat = '%.0f';
            app.nSpinner.ValueChangedFcn = createCallbackFcn(app, @ExecutarButtonPushed, true);
            app.nSpinner.Value = 100;
            app.aEditFieldLabel_3 = uilabel(app.DadosdoPVIPanel);
            app.aEditFieldLabel_3.Text = 'u0 = ';
            app.eU0 = uieditfield(app.DadosdoPVIPanel, 'numeric');
            app.eU0.Value = 9;
            app.gtuvLabel = uilabel(app.DadosdoPVIPanel);
            app.gtuvLabel.Text = 'g(t,u,v) =';
            app.eG = uieditfield(app.DadosdoPVIPanel, 'text');
            app.eG.Value = '-16*u';
            app.aEditFieldLabel_4 = uilabel(app.DadosdoPVIPanel);
            app.aEditFieldLabel_4.Text = 'v0 =';
            app.eV0 = uieditfield(app.DadosdoPVIPanel, 'numeric');
            app.MetodosNumericosButtonGroup = uibuttongroup(app.LeftPanel);
            app.MetodosNumericosButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ExecutarButtonPushed, true);
            app.MetodosNumericosButtonGroup.Title = 'Métodos Numéricos';
            app.EulerButton = uitogglebutton(app.MetodosNumericosButtonGroup);
            app.EulerButton.Text = 'Euler';
            app.EulerButton.Value = true;
            app.EulerMButton = uitogglebutton(app.MetodosNumericosButtonGroup);
            app.EulerMButton.Text = 'Euler M';
            app.RK2Button = uitogglebutton(app.MetodosNumericosButtonGroup);
            app.RK2Button.Text = 'RK2';
            app.RK4Button = uitogglebutton(app.MetodosNumericosButtonGroup);
            app.RK4Button.Text = 'RK4';
            app.TodososMetodosButton = uitogglebutton(app.MetodosNumericosButtonGroup);
            app.TodososMetodosButton.Text = 'Todos os Métodos';
            app.ExecutarButton = uibutton(app.LeftPanel, 'push');
            app.ExecutarButton.ButtonPushedFcn = createCallbackFcn(app, @ExecutarButtonPushed, true);
            app.ExecutarButton.Text = 'Executar';
            app.ResetButton = uibutton(app.LeftPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.GrelhaCheckBox = uicheckbox(app.LeftPanel);
            app.GrelhaCheckBox.ValueChangedFcn = createCallbackFcn(app, @GrelhaCheckBoxValueChanged, true);
            app.GrelhaCheckBox.Text = 'Grelha';
            app.GrelhaCheckBox.Value = true;
            app.ProblemasdinmicosLabel = uilabel(app.LeftPanel);
            app.ProblemasdinmicosLabel.Text = 'Problemas dinâmicos';
            app.ProblemasDropDown = uidropdown(app.LeftPanel);
            app.ProblemasDropDown.Items = {'Mola Massa sem amortecimento', 'Mola Massa com Amortecimento', 'Pêndulo', 'Circuito Elétrico', 'outro'};
            app.ProblemasDropDown.ItemsData = [1 2 3 4 5];
            app.ProblemasDropDown.ValueChangedFcn = createCallbackFcn(app, @ProblemasDropDownValueChanged, true);
            app.ProblemasDropDown.Value = 1;
            app.OcultarLegendaCheckBox = uicheckbox(app.LeftPanel);
            app.OcultarLegendaCheckBox.ValueChangedFcn = createCallbackFcn(app, @OcultarLegendaCheckBoxValueChanged, true);
            app.OcultarLegendaCheckBox.Text = 'Ocultar Legenda';
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.RightPanel);
            app.UIAxes = uiaxes(app.GridLayout2);
            xlabel(app.UIAxes, 't')
            ylabel(app.UIAxes, 'u')
            app.UITable = uitable(app.GridLayout2);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Maquina01MNSED

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end