classdef Maquina05CDI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        Menu                        matlab.ui.container.Menu
        AbrirRelatrioMenu           matlab.ui.container.Menu
        SairMenu                    matlab.ui.container.Menu
        AutoresMenu                 matlab.ui.container.Menu
        TabGroup                    matlab.ui.container.TabGroup
        yfxTab                      matlab.ui.container.Tab
        UITable                     matlab.ui.control.Table
        Panel_2                     matlab.ui.container.Panel
        DerivadaDropDown            matlab.ui.control.DropDown
        DerivadaDropDownLabel       matlab.ui.control.Label
        ExportarTabelaButton        matlab.ui.control.Button
        ExportarGrficoButton        matlab.ui.control.Button
        ExecutarButton_2            matlab.ui.control.Button
        eB_2                        matlab.ui.control.NumericEditField
        aEditFieldLabel_9           matlab.ui.control.Label
        Label_6                     matlab.ui.control.Label
        eH                          matlab.ui.control.NumericEditField
        aEditFieldLabel_8           matlab.ui.control.Label
        Label_5                     matlab.ui.control.Label
        eA_2                        matlab.ui.control.NumericEditField
        aEditFieldLabel_7           matlab.ui.control.Label
        xLabel_2                    matlab.ui.control.Label
        eFx                         matlab.ui.control.EditField
        fxEditFieldLabel            matlab.ui.control.Label
        UIAxes3                     matlab.ui.control.UIAxes
        UIAxes2                     matlab.ui.control.UIAxes
        zfxyTab                     matlab.ui.container.Tab
        Panel                       matlab.ui.container.Panel
        ExportarGrficoButton_2      matlab.ui.control.Button
        Panel_3                     matlab.ui.container.Panel
        LabelLaplaceEDP             matlab.ui.control.Label
        LabelHarmonica              matlab.ui.control.Label
        ExecutarButton              matlab.ui.control.Button
        eD                          matlab.ui.control.NumericEditField
        aEditFieldLabel_6           matlab.ui.control.Label
        Label_4                     matlab.ui.control.Label
        eH2                         matlab.ui.control.NumericEditField
        aEditFieldLabel_5           matlab.ui.control.Label
        Label_3                     matlab.ui.control.Label
        eC                          matlab.ui.control.NumericEditField
        cLabel                      matlab.ui.control.Label
        yLabel                      matlab.ui.control.Label
        Label_2                     matlab.ui.control.Label
        Label                       matlab.ui.control.Label
        xLabel                      matlab.ui.control.Label
        aEditFieldLabel_3           matlab.ui.control.Label
        eB                          matlab.ui.control.NumericEditField
        eH1                         matlab.ui.control.NumericEditField
        aEditFieldLabel_2           matlab.ui.control.Label
        eA                          matlab.ui.control.NumericEditField
        aEditFieldLabel             matlab.ui.control.Label
        eFxy                        matlab.ui.control.EditField
        fxyEditFieldLabel           matlab.ui.control.Label
        UIAxes                      matlab.ui.control.UIAxes
        IntegraoNumricaTab          matlab.ui.container.Tab
        Panel_4                     matlab.ui.container.Panel
        eB_3                        matlab.ui.control.NumericEditField
        bEditFieldLabel             matlab.ui.control.Label
        eN_3                        matlab.ui.control.NumericEditField
        nEditFieldLabel             matlab.ui.control.Label
        eA_3                        matlab.ui.control.NumericEditField
        aEditFieldLabel_10          matlab.ui.control.Label
        eF_3                        matlab.ui.control.EditField
        ExpressoEditFieldLabel      matlab.ui.control.Label
        ResetButton                 matlab.ui.control.Button
        ExecutarButton_3            matlab.ui.control.Button
        IntegraoNumricaButtonGroup  matlab.ui.container.ButtonGroup
        AmbosButton                 matlab.ui.control.RadioButton
        RegradeSimpsonButton        matlab.ui.control.RadioButton
        RegradosTrapziosButton      matlab.ui.control.RadioButton
        UIIntegral                  matlab.ui.control.UIAxes
        UIResultado                 matlab.ui.control.UIAxes
    end

    
    methods (Access = public)
        
        function YN = isHarmonica(~,f)
            syms z(x,y)
            if (diff(f,x,2)+diff(diff(f,y),y)==0)
                YN = 1;
            else
                YN = 0;
            end          
        end
        
        function [x,y,dydx] = NDerivadaDFP(~,f,a,b,h,y)
            x = a:h:b;
            n = length(x);
            if nargin == 5
                y = f(x);
            end
            dydx = zeros(1,n);
            for k = 1:n-1
                dydx(k) = (y(k+1)-y(k))/h;
            end
            dydx(n) = (y(n)-y(n-1))/h;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            ExecutarButtonPushed(app, [])
            ExecutarButton_2Pushed(app,[])
        end

        % Button pushed function: ExecutarButton
        function ExecutarButtonPushed(app, event)
            syms z(x,y)
            strF = app.eFxy.Value;
            f =@(x,y) eval(vectorize(strF));
            a = app.eA.Value; h1 = app.eH1.Value; b = app.eB.Value;
            c = app.eC.Value; h2 = app.eH2.Value; d = app.eD.Value;

            erroFxy = MException("MATLAB:Executar:badFxy","Introduza uma função em x e y");
            if( b < a)
                errordlg("Introduza b > a", "Erro","modal")
                erroFuncao = MException('MATLAB:invalidInput', 'Introduza b > a');
                throw(erroFuncao)
            end
            if( d < c)
                errordlg("Introduza d > c", "Erro","modal")
                erroFuncao = MException('MATLAB:invalidInput', 'Introduza d > c');
                throw(erroFuncao)
            end
            try
                try
                    z = f(x,y);
                    app.eFxy.BackgroundColor = "w";
                catch
                    app.eFxy.BackgroundColor = "r";
                    throw(erroFxy)
                end
                [x,y] = meshgrid(a:h1:b,c:h2:d);
                z = f(x,y);
                surfc(app.UIAxes,x,y,z)
                
                LaplaceEq = ['$',...
                '\displaystyle \frac{\partial^2 f}{\partial x^2}+\frac{\partial^2 f}{\partial y^2}=0',...
                            '$'];
                app.LabelLaplaceEDP.Text = LaplaceEq;
                app.LabelLaplaceEDP.FontColor = "k";
                app.LabelLaplaceEDP.FontSize = 14;
                if isHarmonica(app,f)
                    textoHarmonica = "A função é harmónica";
                    corHarmonica = "g";
                else
                    textoHarmonica = "A função não é harmónica";
                    corHarmonica = "r";
                end
                app.LabelHarmonica.Text = textoHarmonica;
                app.LabelHarmonica.FontColor = corHarmonica;
            catch Me
                errordlg(Me.message,"ERRO","modal")
            end
        end

        % Button pushed function: ExecutarButton_2
        function ExecutarButton_2Pushed(app, event)
            syms y(x)
            strFx = app.eFx.Value;
            f=@(x) eval(vectorize(strFx));
            a = app.eA_2.Value; h = app.eH.Value; b = app.eB_2.Value;

            if( b < a)
                errordlg("Introduza b > a", "Erro","modal")
                app.eA_2.BackgroundColor = [1,0,0]; %[r,g,b]
                app.eA_2.FontColor = "w";
                app.eB_2.BackgroundColor = [1,0,0]; %[r,g,b]
                app.eB_2.FontColor = "w";
                erroFuncao = MException('MATLAB:invalidInput', 'Introduza b > a');
                throw(erroFuncao)
            else

                app.eB.BackgroundColor = "w";
                app.eB.FontColor = [0,0,0];
            end


            escolhaddMetodos = app.DerivadaDropDown.Value;
            cla(app.UIAxes2);

            if(escolhaddMetodos == "Progressiva 2 pontos")
                dydxSym = diff(f(x));
                c =@(x) eval(vectorize(char(dydxSym)));
                [x,y,dydx2PP] = NDerivadaDFP(app,f,a,b,h);
                dydxExata = c(x);
                erro2PP = abs(dydxExata -dydx2PP);
                dydxExata = zeros(size(x)); % Inicializa um vetor de zeros do mesmo tamanho que x para armazenar os valores calculados de dy/dx

                for i = 1:length(x) % Loop para percorrer os elementos de x
                    dydxExata(i) = c(x(i)); % Calcula o valor de dy/dx para x e armazena o resultado em dydxExata
                end

                % calcular o valor de dy/dx para cada elemento em x usando a função c e armazena os valores em dydxExata.
                % resultado final é um vetor dydxExata que contém os valores calculados dy/dx correspondentes a cada elemento em x.

                tabela = array2table([x.',y.',dydxExata.', dydx2PP.',erro2PP.']);
                app.UITable.Data = tabela;
                plot(app.UIAxes2,x,y,"-r+")
                hold(app.UIAxes2,"on")
                plot(app.UIAxes2,x, dydxExata, "-bo")
                plot(app.UIAxes2,x,dydx2PP,"-g*")
                hold(app.UIAxes2,"off")
                nomeColunas = {'x','Exata','ExataDerivada','Progressiva 2p','Erro'};
                legendaGrafico = ["Exata","Exata Derivada","Progressiva 2p"];
                AxisLatex =['$','\frac{dy}{dx}=',latex(dydxSym),'$'];
                cla(app.UIAxes3)
                text(app.UIAxes3,0.1,0.5,AxisLatex,"Interpreter","latex","FontSize",18)
            end
            if(escolhaddMetodos == "Regressiva 2 pontos")
                dydxSym = diff(f(x));
                c =@(x) eval(vectorize(char(dydxSym)));
                [x,y,dydx2PR] = NDerivacao2PR(f,a,b,h,y);
                dydxExata = c(x);
                erro2PR = abs(dydxExata -dydx2PR);
                dydxExata = zeros(size(x)); 
                for i = 1:length(x) 
                    dydxExata(i) = c(x(i)); 
                end                
                tabela = array2table([x.',y.',dydxExata.', dydx2PR.',erro2PR.']);
                app.UITable.Data = tabela;
                plot(app.UIAxes2,x,y,"-r+")
                hold(app.UIAxes2,"on")
                plot(app.UIAxes2,x, dydxExata, "-bo")
                plot(app.UIAxes2,x,dydx2PR,"-g*")
                hold(app.UIAxes2,"off")
                nomeColunas = {'x','Exata','ExataDerivada','Regressiva 2p','Erro'};
                legendaGrafico = ["Exata","Exata Derivada","Regressiva 2p"];
                AxisLatex =['$','\frac{dy}{dx}=',latex(dydxSym),'$'];
                cla(app.UIAxes3)
                text(app.UIAxes3,0.1,0.5,AxisLatex,"Interpreter","latex","FontSize",18)
            end
            if(escolhaddMetodos == "Progressiva 3 pontos")
                dydxSym = diff(f(x));
                c =@(x) eval(vectorize(char(dydxSym)));
                [x,y,dydx3PP] = NDerivacao3PP(f,a,b,h,y);
                dydxExata = c(x);
                erro3PP = abs(dydxExata -dydx3PP);
                dydxExata = zeros(size(x)); 
                for i = 1:length(x) 
                    dydxExata(i) = c(x(i)); 
                end               
                tabela = array2table([x.',y.',dydxExata.', dydx3PP.',erro3PP.']);
                app.UITable.Data = tabela;
                plot(app.UIAxes2,x,y,"-r+")
                hold(app.UIAxes2,"on")
                plot(app.UIAxes2,x, dydxExata, "-bo")
                plot(app.UIAxes2,x,dydx3PP,"-g*")
                hold(app.UIAxes,"off")
                nomeColunas = {'x','Exata','ExataDerivada','Progressiva 3p','Erro'};
                legendaGrafico = ["Exata","Exata Derivada","Progressiva 3p"];
                AxisLatex =['$','\frac{dy}{dx}=',latex(dydxSym),'$'];
                cla(app.UIAxes3)      
                text(app.UIAxes3,0.1,0.5,AxisLatex,"Interpreter","latex","FontSize",18)
            end
            if(escolhaddMetodos == "Regressiva 3 pontos")
                dydxSym = diff(f(x));
                c =@(x) eval(vectorize(char(dydxSym)));
                [x,y,dydx3PR] = NDerivacao3PR(f,a,b,h,y);
                dydxExata = c(x);
                erro3PR = abs(dydxExata -dydx3PR);
                dydxExata = zeros(size(x)); 
                for i = 1:length(x) 
                    dydxExata(i) = c(x(i)); 
                end
                tabela = array2table([x.',y.',dydxExata.', dydx3PR.',erro3PR.']);
                app.UITable.Data = tabela;
                plot(app.UIAxes2,x,y,"-r+")
                hold(app.UIAxes2,"on")
                plot(app.UIAxes2,x, dydxExata, "-bo")
                plot(app.UIAxes2,x,dydx3PR,"-g*")
                hold(app.UIAxes,"off")
                nomeColunas = {'x','Exata','ExataDerivada','Regressiva 3p','Erro'};
                legendaGrafico = ["Exata","Exata Derivada","Regressiva 3p"];
                AxisLatex =['$','\frac{dy}{dx}=',latex(dydxSym),'$'];
                cla(app.UIAxes3)
                text(app.UIAxes3,0.1,0.5,AxisLatex,"Interpreter","latex","FontSize",18)
            end
            if(escolhaddMetodos == "Centrada 3 pontos")
                dydxSym = diff(f(x));
                c =@(x) eval(vectorize(char(dydxSym)));
                [x,y,dydx3PC] = NDerivacao3PC(f,a,b,h,y);
                dydxExata = c(x);
                erro3PC = abs(dydxExata -dydx3PC);
                dydxExata = zeros(size(x)); 
                for i = 1:length(x) 
                    dydxExata(i) = c(x(i));
                end
                tabela = array2table([x.',y.',dydxExata.', dydx3PC.',erro3PC.']);
                app.UITable.Data = tabela;
                plot(app.UIAxes2,x,y,"-r+")
                hold(app.UIAxes2,"on")
                plot(app.UIAxes2,x, dydxExata, "-bo")
                plot(app.UIAxes2,x,dydx3PC,"-g*")
                hold(app.UIAxes2,"off")
                nomeColunas = {'x','Exata','ExataDerivada','Centrada 3p','Erro'};
                legendaGrafico = ["Exata","Exata Derivada","Centrada 3p"];
                AxisLatex =['$','\frac{dy}{dx}=',latex(dydxSym),'$'];
                cla(app.UIAxes3)               
                text(app.UIAxes3,0.1,0.5,AxisLatex,"Interpreter","latex","FontSize",18)
            end
            if(escolhaddMetodos == "2ª derivada 3 pontos")
                dydxSym = diff(f(x));
                c =@(x) eval(vectorize(char(dydxSym)));
                [x,y,dydx2D] = NDerivacao2D(f,a,b,h,y);
                dydxExata = c(x);
                erro2D = abs(dydxExata -dydx2D);
                dydxExata = zeros(size(x)); 
                for i = 1:length(x) 
                    dydxExata(i) = c(x(i)); 
                end                
                tabela = array2table([x.',y.',dydxExata.', dydx2D.',erro2D.']);
                app.UITable.Data = tabela;
                plot(app.UIAxes2,x,y,"-r+")
                hold(app.UIAxes2,"on")
                plot(app.UIAxes2,x, dydxExata, "-bo")
                plot(app.UIAxes2,x,dydx2D,"-g*")
                hold(app.UIAxes2,"off")
                nomeColunas = {'x','Exata','ExataDerivada','2ªDerivada 3p','Erro'};
                legendaGrafico = ["Exata","Exata Derivada","2ª Derivada"];
                AxisLatex =['$','\frac{dy}{dx}=',latex(dydxSym),'$'];
                cla(app.UIAxes3)
                text(app.UIAxes3,0.1,0.5,AxisLatex,"Interpreter","latex","FontSize",18)
            end

            app.UITable.ColumnName = nomeColunas;
            legend(app.UIAxes2,legendaGrafico)


            try

            catch ME
                errordlg(ME.message,"ERRO","modal")
            end

        end

        % Menu selected function: AutoresMenu
        function AutoresMenuSelected(app, event)
            Autores
        end

        % Menu selected function: SairMenu
        function SairMenuSelected(app, event)
            SN = questdlg("Deseja sair","SAIR","Sim","Não","Sim");
            if strcmp(SN,"Sim")
                delete(app.UIFigure)
            end
        end

        % Menu selected function: AbrirRelatrioMenu
        function AbrirRelatrioMenuSelected(app, event)
            open("Ricardo_Duarte-2022137878_Pedro_Jacome-2022137038_Guilherme_Domingos-2022136668.pdf")
        end

        % Button pushed function: ExportarGrficoButton
        function ExportarGrficoButtonPushed(app, event)
            [file, path] = uiputfile({'.png','PNG files (.png)'},'Save As');
            if file == 0
                return;
            end
            filename = fullfile(path, file);
            
            exportgraphics(app.UIAxes2, filename, 'Resolution', 300);
            msgbox(['UI exported to ', filename]);
        end

        % Button pushed function: ExportarTabelaButton
        function ExportarTabelaButtonPushed(app, event)
            dados = app.UITable.Data;
            colunas = app.UITable.ColumnName;
            dados = convertNestedTablesToCells(dados);
            tabela = array2table(dados, 'VariableNames', colunas);
            nomeFicheiro = 'dados.xlsx';

            if isfile("dados.xlsx")
                delete("dados.xlsx");
            end

            writetable(tabela, nomeFicheiro);
            msgbox('Tabela exportada com sucesso.', 'Concluído');

            function data = convertNestedTablesToCells(data)
                if istable(data)
                    data = table2cell(data);
                elseif iscell(data)
                    for i = 1:numel(data)
                        data{i} = convertNestedTablesToCells(data{i});
                    end
                end
            end

        end

        % Button pushed function: ExportarGrficoButton_2
        function ExportarGrficoButton_2Pushed(app, event)
            [file, path] = uiputfile({'.png','PNG files (.png)'},'Save As');
            if file == 0
                return;
            end
            filename = fullfile(path, file);
            
            exportgraphics(app.UIAxes, filename, 'Resolution', 300);
            msgbox(['UI exported to ', filename]);
        end

        % Button pushed function: ExecutarButton_3
        function ExecutarButton_3Pushed(app, event)
            strF = app.eF_3.Value;
            f = @(x) eval(vectorize(strF));
            a = app.eA_3.Value; n = app.eN_3.Value; b = app.eB_3.Value;
            if( b < a)
                errordlg("Introduza b > a", "Erro","modal")
                erroFuncao = MException('MATLAB:invalidInput', 'Introduza b > a');
                throw(erroFuncao)
            end
            cla(app.UIIntegral);
            cla(app.UIResultado);
            int = integral(f,a,b);
            % Calcular a integral usando a Regra dos Trapézios
            T = RTrapezios(f, a, b, n);
            % Calcular a integral usando a Regra de Simpson
            S = RSimpson(f, a, b, n);

            escolhabgMetodos = app.IntegraoNumricaButtonGroup.SelectedObject;
            rbMetodoSelecionado = find([app.RegradosTrapziosButton,...
                app.RegradeSimpsonButton,...
                app.AmbosButton]==escolhabgMetodos);

            AxisLatex = sprintf('\\int_{%i}^{%i} %s =%s',a,b, strF, num2str(int));
            text(app.UIIntegral, 0.1, 0.5, AxisLatex, 'Units', 'normalized', 'FontSize', 18);

            switch rbMetodoSelecionado
                case 1
                    result_str = sprintf('Regra dos Trapézios: %f\n',T);
                    text(app.UIResultado, 0.05, 0.4, result_str, 'Units', 'normalized', 'FontSize', 18);
                case 2
                    result_str = sprintf('Regra de Simpson: %f\n',S);
                    text(app.UIResultado, 0.05, 0.4, result_str, 'Units', 'normalized', 'FontSize', 18);
                case 3
                    result_str = sprintf('Regra dos Trapézios: %f\nRegra de Simpson: %f', T, S);
                    text(app.UIResultado, 0.05, 0.5, result_str, 'Units', 'normalized', 'FontSize', 18);
            end

        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.eF_3.Value = '(x+3)*exp(x/2)';
            app.eA_3.Value = 0;
            app.eN_3.Value = 25;
            app.eB_3.Value = 10 ;
            ExecutarButton_3Pushed(app, event); 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.AbrirRelatrioMenu = uimenu(app.Menu);
            app.AbrirRelatrioMenu.MenuSelectedFcn = createCallbackFcn(app, @AbrirRelatrioMenuSelected, true);
            app.AbrirRelatrioMenu.Text = 'Abrir Relatório';
            app.SairMenu = uimenu(app.Menu);
            app.SairMenu.MenuSelectedFcn = createCallbackFcn(app, @SairMenuSelected, true);
            app.SairMenu.Text = 'Sair';
            app.AutoresMenu = uimenu(app.UIFigure);
            app.AutoresMenu.MenuSelectedFcn = createCallbackFcn(app, @AutoresMenuSelected, true);
            app.AutoresMenu.Text = 'Autores';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.yfxTab = uitab(app.TabGroup);
            app.yfxTab.Title = 'y=f(x)';
            app.UIAxes2 = uiaxes(app.yfxTab);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.Panel_2 = uipanel(app.yfxTab);
            app.UIAxes3 = uiaxes(app.Panel_2);
            app.UIAxes3.XTick = [];
            app.UIAxes3.YTick = [];
            app.UIAxes3.ZTick = [];
            app.fxEditFieldLabel = uilabel(app.Panel_2);
            app.fxEditFieldLabel.Text = 'f(x) =';
            app.eFx = uieditfield(app.Panel_2, 'text');
            app.eFx.Value = 'sin(x)';
            app.xLabel_2 = uilabel(app.Panel_2);
            app.xLabel_2.Text = 'x =';
            app.aEditFieldLabel_7 = uilabel(app.Panel_2);
            app.aEditFieldLabel_7.Text = 'a';
            app.eA_2 = uieditfield(app.Panel_2, 'numeric');
            app.Label_5 = uilabel(app.Panel_2);
            app.Label_5.Text = ':';
            app.aEditFieldLabel_8 = uilabel(app.Panel_2);
            app.aEditFieldLabel_8.Text = 'h';
            app.eH = uieditfield(app.Panel_2, 'numeric');
            app.eH.Value = 0.2;
            app.Label_6 = uilabel(app.Panel_2);
            app.Label_6.Text = ':';
            app.aEditFieldLabel_9 = uilabel(app.Panel_2);
            app.aEditFieldLabel_9.Text = 'b';
            app.eB_2 = uieditfield(app.Panel_2, 'numeric');
            app.eB_2.Value = 7;
            app.ExecutarButton_2 = uibutton(app.Panel_2, 'push');
            app.ExecutarButton_2.ButtonPushedFcn = createCallbackFcn(app, @ExecutarButton_2Pushed, true);
            app.ExecutarButton_2.Text = 'Executar';
            app.ExportarGrficoButton = uibutton(app.Panel_2, 'push');
            app.ExportarGrficoButton.ButtonPushedFcn = createCallbackFcn(app, @ExportarGrficoButtonPushed, true);
            app.ExportarGrficoButton.Text = 'Exportar Gráfico';
            app.ExportarTabelaButton = uibutton(app.Panel_2, 'push');
            app.ExportarTabelaButton.ButtonPushedFcn = createCallbackFcn(app, @ExportarTabelaButtonPushed, true);
            app.ExportarTabelaButton.Text = 'Exportar Tabela';
            app.DerivadaDropDownLabel = uilabel(app.Panel_2);
            app.DerivadaDropDownLabel.Text = 'Derivada :';
            app.DerivadaDropDown = uidropdown(app.Panel_2);
            app.DerivadaDropDown.Items = {'Progressiva 2 pontos', 'Regressiva 2 pontos', 'Progressiva 3 pontos', 'Regressiva 3 pontos', 'Centrada 3 pontos', '2ª derivada 3 pontos'};
            app.DerivadaDropDown.Value = 'Progressiva 2 pontos';
            app.UITable = uitable(app.yfxTab);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.zfxyTab = uitab(app.TabGroup);
            app.zfxyTab.Title = 'z=f(x,y)';
            app.UIAxes = uiaxes(app.zfxyTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Panel = uipanel(app.zfxyTab);
            app.fxyEditFieldLabel = uilabel(app.Panel);
            app.fxyEditFieldLabel.Text = 'f(x,y) =';
            app.eFxy = uieditfield(app.Panel, 'text');
            app.eFxy.Value = 'x^2+y^2';
            app.aEditFieldLabel = uilabel(app.Panel);
            app.aEditFieldLabel.Text = 'a';
            app.eA = uieditfield(app.Panel, 'numeric');
            app.eA.Value = -2;
            app.aEditFieldLabel_2 = uilabel(app.Panel);
            app.aEditFieldLabel_2.Text = 'h1';
            app.eH1 = uieditfield(app.Panel, 'numeric');
            app.eH1.Value = 0.2;
            app.eB = uieditfield(app.Panel, 'numeric');
            app.eB.Value = 2;
            app.aEditFieldLabel_3 = uilabel(app.Panel);
            app.aEditFieldLabel_3.Text = 'b';
            app.xLabel = uilabel(app.Panel);
            app.xLabel.Text = 'x =';
            app.Label = uilabel(app.Panel);
            app.Label.Text = ':';
            app.Label_2 = uilabel(app.Panel);
            app.Label_2.Text = ':';
            app.yLabel = uilabel(app.Panel);
            app.yLabel.Text = 'y =';
            app.cLabel = uilabel(app.Panel);
            app.cLabel.Text = 'c';
            app.eC = uieditfield(app.Panel, 'numeric');
            app.eC.Value = -2;
            app.Label_3 = uilabel(app.Panel);
            app.Label_3.Text = ':';
            app.aEditFieldLabel_5 = uilabel(app.Panel);
            app.aEditFieldLabel_5.Text = 'h2';
            app.eH2 = uieditfield(app.Panel, 'numeric');
            app.eH2.Value = 0.2;
            app.Label_4 = uilabel(app.Panel);
            app.Label_4.Text = ':';
            app.aEditFieldLabel_6 = uilabel(app.Panel);
            app.aEditFieldLabel_6.Text = 'd';
            app.eD = uieditfield(app.Panel, 'numeric');
            app.eD.Value = 2;
            app.ExecutarButton = uibutton(app.Panel, 'push');
            app.ExecutarButton.ButtonPushedFcn = createCallbackFcn(app, @ExecutarButtonPushed, true);
            app.ExecutarButton.Text = 'Executar';
            app.Panel_3 = uipanel(app.Panel);
            app.LabelHarmonica = uilabel(app.Panel_3);
            app.LabelHarmonica.Text = 'A função não é harmónica';
            app.LabelLaplaceEDP = uilabel(app.Panel_3);
            app.LabelLaplaceEDP.Interpreter = 'latex';
            app.LabelLaplaceEDP.Text = 'EDP_eqLaplace';
            app.ExportarGrficoButton_2 = uibutton(app.Panel, 'push');
            app.ExportarGrficoButton_2.ButtonPushedFcn = createCallbackFcn(app, @ExportarGrficoButton_2Pushed, true);
            app.ExportarGrficoButton_2.Text = 'Exportar Gráfico';
            app.IntegraoNumricaTab = uitab(app.TabGroup);
            app.IntegraoNumricaTab.Title = 'Integração Numérica';
            app.UIResultado = uiaxes(app.IntegraoNumricaTab);
            title(app.UIResultado, 'Resultado')
            app.UIResultado.XTick = [];
            app.UIResultado.YTick = [];
            app.UIIntegral = uiaxes(app.IntegraoNumricaTab);
            title(app.UIIntegral, 'Integral')
            app.UIIntegral.XTick = [];
            app.UIIntegral.YTick = [];
            app.IntegraoNumricaButtonGroup = uibuttongroup(app.IntegraoNumricaTab);
            app.IntegraoNumricaButtonGroup.Title = 'Integração Numérica';
            app.RegradosTrapziosButton = uiradiobutton(app.IntegraoNumricaButtonGroup);
            app.RegradosTrapziosButton.Text = 'Regra dos Trapézios';
            app.RegradosTrapziosButton.Value = true;
            app.RegradeSimpsonButton = uiradiobutton(app.IntegraoNumricaButtonGroup);
            app.RegradeSimpsonButton.Text = 'Regra de Simpson';
            app.AmbosButton = uiradiobutton(app.IntegraoNumricaButtonGroup);
            app.AmbosButton.Text = 'Ambos';
            app.ExecutarButton_3 = uibutton(app.IntegraoNumricaTab, 'push');
            app.ExecutarButton_3.ButtonPushedFcn = createCallbackFcn(app, @ExecutarButton_3Pushed, true);
            app.ExecutarButton_3.Text = 'Executar';
            app.ResetButton = uibutton(app.IntegraoNumricaTab, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.Panel_4 = uipanel(app.IntegraoNumricaTab);
            app.ExpressoEditFieldLabel = uilabel(app.Panel_4);
            app.ExpressoEditFieldLabel.Text = 'Expressão';
            app.eF_3 = uieditfield(app.Panel_4, 'text');
            app.eF_3.Value = '(x+2)*exp(x/2)';
            app.aEditFieldLabel_10 = uilabel(app.Panel_4);
            app.aEditFieldLabel_10.Text = 'a';
            app.eA_3 = uieditfield(app.Panel_4, 'numeric');
            app.nEditFieldLabel = uilabel(app.Panel_4);
            app.nEditFieldLabel.Text = 'n';
            app.eN_3 = uieditfield(app.Panel_4, 'numeric');
            app.eN_3.Value = 25;
            app.bEditFieldLabel = uilabel(app.Panel_4);
            app.bEditFieldLabel.Text = 'b';
            app.eB_3 = uieditfield(app.Panel_4, 'numeric');
            app.eB_3.Value = 10;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Maquina05CDI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end