classdef Maquina01CDI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        zfxyTab                      matlab.ui.container.Tab
        Panel                        matlab.ui.container.Panel
        LabelHarmonica               matlab.ui.control.Label
        ExecutarButton               matlab.ui.control.Button
        eD                           matlab.ui.control.NumericEditField
        aEditFieldLabel_6            matlab.ui.control.Label
        Label_4                      matlab.ui.control.Label
        eH2                          matlab.ui.control.NumericEditField
        aEditFieldLabel_5            matlab.ui.control.Label
        Label_3                      matlab.ui.control.Label
        eC                           matlab.ui.control.NumericEditField
        cLabel                       matlab.ui.control.Label
        yLabel                       matlab.ui.control.Label
        Label_2                      matlab.ui.control.Label
        Label                        matlab.ui.control.Label
        xLabel                       matlab.ui.control.Label
        aEditFieldLabel_3            matlab.ui.control.Label
        eB                           matlab.ui.control.NumericEditField
        eH1                          matlab.ui.control.NumericEditField
        aEditFieldLabel_2            matlab.ui.control.Label
        eA                           matlab.ui.control.NumericEditField
        aEditFieldLabel              matlab.ui.control.Label
        eFxy                         matlab.ui.control.EditField
        fxyEditFieldLabel            matlab.ui.control.Label
        UIMostrador                  matlab.ui.control.UIAxes
        UIAxes                       matlab.ui.control.UIAxes
        yfxTab                       matlab.ui.container.Tab
        Panel_2                      matlab.ui.container.Panel
        ButtonGroup                  matlab.ui.container.ButtonGroup
        Button3                      matlab.ui.control.RadioButton
        Button2                      matlab.ui.control.RadioButton
        DiferenasProgressivasButton  matlab.ui.control.RadioButton
        ExecutarButton2              matlab.ui.control.Button
        Label_6                      matlab.ui.control.Label
        Label_5                      matlab.ui.control.Label
        xLabel2                      matlab.ui.control.Label
        aEditFieldLabel_9            matlab.ui.control.Label
        eB2                          matlab.ui.control.NumericEditField
        eH                           matlab.ui.control.NumericEditField
        aEditFieldLabel_8            matlab.ui.control.Label
        eA2                          matlab.ui.control.NumericEditField
        aEditFieldLabel_7            matlab.ui.control.Label
        eFx                          matlab.ui.control.EditField
        fxLabel                      matlab.ui.control.Label
        UIAxes2                      matlab.ui.control.UIAxes
    end

    
    methods (Access = public)
        
        function YN = isHarmonica(~,f)
            syms z(x,y)
            if(diff(f,x,2)+diff(f,y,2) == 0)
                YN = 1;
            else
                YN = 0;
            end
        end
        
        function [x,y,dydx] = NDerivadaDFP(~,f,a,b,h,y)
            x = a:h:b;
            n = length(x);
            if nargin == 5
                y = f(x);
            end
            dydx = zeros(1,n);
            for k = 1:n-1 % até n-1 para não rebentar na ultima iteração
                dydx(k) = (y(k+1)-y(k))/h;
            end
            dydx(n) = (y(n) - y(n-1))/h;
            %dydx(n) = dydx(n-1); erro negligivel em valores baixos de h
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            ExecutarButtonPushed(app, [])
            ExecutarButton2Pushed(app, [])
        end

        % Button pushed function: ExecutarButton
        function ExecutarButtonPushed(app, event)
            syms z(x,y)
            strF = app.eFxy.Value;
            f =@(x,y) eval(vectorize(strF));
            a = app.eA.Value; h1 = app.eH1.Value; b = app.eB.Value;
            c = app.eC.Value; h2 = app.eH2.Value; d = app.eD.Value;

            erroFxy = MException("MATLAB:Executar:badFxy","Introduza uma função em x e y");
            % outras
            try
                try
                    z = f(x,y);
                    app.eFxy.BackgroundColor = "w";
                catch
                    app.eFxy.BackgroundColor = "r";
                    throw(erroFxy)
                end
                [x,y] = meshgrid(a:h1:b,c:h2:d);
                z = f(x,y);
                surfc(app.UIAxes,x,y,z)

                cla(app.UIMostrador)
                eqLaplace = ['$',...
                    ' \frac{\partial^2 f}{\partial x^2}+\frac{\partial^2 f}{\partial y^2}=0',...
                    '$'];
                text(app.UIMostrador,0.25,0.5,eqLaplace,"Interpreter","latex","FontSize",18)
    
                if isHarmonica(app,f)
                    textoHarmonica = "A Função é Harmónica";
                    corHarmonica = "g";
                else
                    textoHarmonica = "A Função não é Harmónica";
                    corHarmonica = "r";
                end

                app.LabelHarmonica.Text = textoHarmonica;
                app.LabelHarmonica.FontColor = corHarmonica;

            catch Me
                errordlg(Me.message,"ERRO","modal")
            end
        end

        % Button pushed function: ExecutarButton2
        function ExecutarButton2Pushed(app, event)
            syms y(x)
            strF = app.eFx.Value;
            f =@(x) eval(vectorize(strF));
            a = app.eA2.Value; h = app.eH.Value; b = app.eB2.Value;

            dydxSym = diff(f,x);
            dfdx =@(x) eval(vectorize(char(dydxSym)));
            

            [x,y,dydxDFP] = NDerivadaDFP(app,f,a,b,h);
            dydxExata = dfdx(x);
            erroDFP = abs(dydxExata - dydxDFP);
            tabelaresultados = table([x.',y.',dydxExata.',dydxDFP.',erroDFP.']);

            plot(app.UIAxes2,x,y,"-r*");
            hold(app.UIAxes2,"on");
            plot(app.UIAxes2,x,dydxExata,"-bo");
            plot(app.UIAxes2,x,dydxDFP,"-g+");
            hold(app.UIAxes2,"off");

            %adicionar legenda e afins


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.zfxyTab = uitab(app.TabGroup);
            app.zfxyTab.Title = 'z=f(x,y)';
            app.UIAxes = uiaxes(app.zfxyTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Panel = uipanel(app.zfxyTab);
            app.UIMostrador = uiaxes(app.Panel);
            app.UIMostrador.XTick = [];
            app.UIMostrador.YTick = [];
            app.fxyEditFieldLabel = uilabel(app.Panel);
            app.fxyEditFieldLabel.Text = 'f(x,y) =';
            app.eFxy = uieditfield(app.Panel, 'text');
            app.eFxy.Value = 'x^2+y^2';
            app.aEditFieldLabel = uilabel(app.Panel);
            app.aEditFieldLabel.Text = 'a';
            app.eA = uieditfield(app.Panel, 'numeric');
            app.eA.Value = -2;
            app.aEditFieldLabel_2 = uilabel(app.Panel);
            app.aEditFieldLabel_2.Text = 'h1';
            app.eH1 = uieditfield(app.Panel, 'numeric');
            app.eH1.Value = 0.2;
            app.eB = uieditfield(app.Panel, 'numeric');
            app.eB.Value = 2;
            app.aEditFieldLabel_3 = uilabel(app.Panel);
            app.aEditFieldLabel_3.Text = 'b';
            app.xLabel = uilabel(app.Panel);
            app.xLabel.Text = 'x =';
            app.Label = uilabel(app.Panel);
            app.Label.Text = ':';
            app.Label_2 = uilabel(app.Panel);
            app.Label_2.Text = ':';
            app.yLabel = uilabel(app.Panel);
            app.yLabel.Text = 'y =';
            app.cLabel = uilabel(app.Panel);
            app.cLabel.Text = 'c';
            app.eC = uieditfield(app.Panel, 'numeric');
            app.eC.Value = -2;
            app.Label_3 = uilabel(app.Panel);
            app.Label_3.Text = ':';
            app.aEditFieldLabel_5 = uilabel(app.Panel);
            app.aEditFieldLabel_5.Text = 'h2';
            app.eH2 = uieditfield(app.Panel, 'numeric');
            app.eH2.Value = 0.2;
            app.Label_4 = uilabel(app.Panel);
            app.Label_4.Text = ':';
            app.aEditFieldLabel_6 = uilabel(app.Panel);
            app.aEditFieldLabel_6.Text = 'd';
            app.eD = uieditfield(app.Panel, 'numeric');
            app.eD.Value = 2;
            app.ExecutarButton = uibutton(app.Panel, 'push');
            app.ExecutarButton.ButtonPushedFcn = createCallbackFcn(app, @ExecutarButtonPushed, true);
            app.ExecutarButton.Text = 'Executar';
            app.LabelHarmonica = uilabel(app.Panel);
            app.LabelHarmonica.Text = 'A  função não é Harmónica';
            app.yfxTab = uitab(app.TabGroup);
            app.yfxTab.Title = 'y=f(x)';
            app.UIAxes2 = uiaxes(app.yfxTab);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.Panel_2 = uipanel(app.yfxTab);
            app.fxLabel = uilabel(app.Panel_2);
            app.fxLabel.Text = 'f(x) =';
            app.eFx = uieditfield(app.Panel_2, 'text');
            app.eFx.Value = 'sin(x)';
            app.aEditFieldLabel_7 = uilabel(app.Panel_2);
            app.aEditFieldLabel_7.Text = 'a';
            app.eA2 = uieditfield(app.Panel_2, 'numeric');
            app.aEditFieldLabel_8 = uilabel(app.Panel_2);
            app.aEditFieldLabel_8.Text = 'h';
            app.eH = uieditfield(app.Panel_2, 'numeric');
            app.eH.Value = 0.2;
            app.eB2 = uieditfield(app.Panel_2, 'numeric');
            app.eB2.Value = 2;
            app.aEditFieldLabel_9 = uilabel(app.Panel_2);
            app.aEditFieldLabel_9.Text = 'b';
            app.xLabel2 = uilabel(app.Panel_2);
            app.xLabel2.Text = 'x =';
            app.Label_5 = uilabel(app.Panel_2);
            app.Label_5.Text = ':';
            app.Label_6 = uilabel(app.Panel_2);
            app.Label_6.Text = ':';
            app.ExecutarButton2 = uibutton(app.Panel_2, 'push');
            app.ExecutarButton2.ButtonPushedFcn = createCallbackFcn(app, @ExecutarButton2Pushed, true);
            app.ExecutarButton2.Text = 'Executar';
            app.ButtonGroup = uibuttongroup(app.Panel_2);
            app.ButtonGroup.Title = 'Button Group';
            app.DiferenasProgressivasButton = uiradiobutton(app.ButtonGroup);
            app.DiferenasProgressivasButton.Text = 'Diferenças Progressivas';
            app.DiferenasProgressivasButton.Value = true;
            app.Button2 = uiradiobutton(app.ButtonGroup);
            app.Button2.Text = 'Button2';
            app.Button3 = uiradiobutton(app.ButtonGroup);
            app.Button3.Text = 'Button3';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Maquina01CDI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end