classdef Maquina03bCDI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        Menu                         matlab.ui.container.Menu
        AbrirRelatrioMenu            matlab.ui.container.Menu
        SairMenu                     matlab.ui.container.Menu
        AutoresMenu                  matlab.ui.container.Menu
        TabGroup                     matlab.ui.container.TabGroup
        yfxTab                       matlab.ui.container.Tab
        UITable                      matlab.ui.control.Table
        Panel_2                      matlab.ui.container.Panel
        DerivadaDropDown             matlab.ui.control.DropDown
        DerivadaDropDownLabel        matlab.ui.control.Label
        ExportarTabelaButton         matlab.ui.control.Button
        ExportarGrficoButton         matlab.ui.control.Button
        ExecutarButton_2             matlab.ui.control.Button
        eB_2                         matlab.ui.control.NumericEditField
        aEditFieldLabel_9            matlab.ui.control.Label
        Label_6                      matlab.ui.control.Label
        eH                           matlab.ui.control.NumericEditField
        aEditFieldLabel_8            matlab.ui.control.Label
        Label_5                      matlab.ui.control.Label
        eA_2                         matlab.ui.control.NumericEditField
        aEditFieldLabel_7            matlab.ui.control.Label
        xLabel_2                     matlab.ui.control.Label
        eFx                          matlab.ui.control.EditField
        fxEditFieldLabel             matlab.ui.control.Label
        UIAxes3                      matlab.ui.control.UIAxes
        UIAxes2                      matlab.ui.control.UIAxes
        zfxyTab                      matlab.ui.container.Tab
        Panel                        matlab.ui.container.Panel
        Panel_3                      matlab.ui.container.Panel
        LabelLaplaceEDP              matlab.ui.control.Label
        LabelHarmonica               matlab.ui.control.Label
        ExecutarButton               matlab.ui.control.Button
        eD                           matlab.ui.control.NumericEditField
        aEditFieldLabel_6            matlab.ui.control.Label
        Label_4                      matlab.ui.control.Label
        eH2                          matlab.ui.control.NumericEditField
        aEditFieldLabel_5            matlab.ui.control.Label
        Label_3                      matlab.ui.control.Label
        eC                           matlab.ui.control.NumericEditField
        cLabel                       matlab.ui.control.Label
        yLabel                       matlab.ui.control.Label
        Label_2                      matlab.ui.control.Label
        Label                        matlab.ui.control.Label
        xLabel                       matlab.ui.control.Label
        aEditFieldLabel_3            matlab.ui.control.Label
        eB                           matlab.ui.control.NumericEditField
        eH1                          matlab.ui.control.NumericEditField
        aEditFieldLabel_2            matlab.ui.control.Label
        eA                           matlab.ui.control.NumericEditField
        aEditFieldLabel              matlab.ui.control.Label
        eFxy                         matlab.ui.control.EditField
        fxyEditFieldLabel            matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
        IntegraoNumricaTab           matlab.ui.container.Tab
        IntegraoNumricaListBox       matlab.ui.control.ListBox
        IntegraoNumricaListBoxLabel  matlab.ui.control.Label
        EditField                    matlab.ui.control.NumericEditField
        EditFieldLabel               matlab.ui.control.Label
    end

    
    methods (Access = public)
        
        function YN = isHarmonica(~,f)
            syms z(x,y)
            if (diff(f,x,2)+diff(diff(f,y),y)==0)
                YN = 1;
            else
                YN = 0;
            end          
        end
        
        function [x,y,dydx] = NDerivadaDFP(~,f,a,b,h,y)
            x = a:h:b;
            n = length(x);
            if nargin == 5
                y = f(x);
            end
            dydx = zeros(1,n);
            for k = 1:n-1
                dydx(k) = (y(k+1)-y(k))/h;
            end
            dydx(n) = (y(n)-y(n-1))/h;
            %dydx(n) = dydx(n-1)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            ExecutarButtonPushed(app, [])
            ExecutarButton_2Pushed(app,[])
        end

        % Button pushed function: ExecutarButton
        function ExecutarButtonPushed(app, event)
            syms z(x,y)
            strF = app.eFxy.Value;
            f =@(x,y) eval(vectorize(strF));
            a = app.eA.Value; h1 = app.eH1.Value; b = app.eB.Value;
            c = app.eC.Value; h2 = app.eH2.Value; d = app.eD.Value;

            erroFxy = MException("MATLAB:Executar:badFxy","Introduza uma função em x e y");
            % outras
            try
                try
                    z = f(x,y);
                    app.eFxy.BackgroundColor = "w";
                catch
                    app.eFxy.BackgroundColor = "r";
                    throw(erroFxy)
                end
                [x,y] = meshgrid(a:h1:b,c:h2:d);
                z = f(x,y);
                surfc(app.UIAxes,x,y,z)
                
                LaplaceEq = ['$',...
                '\displaystyle \frac{\partial^2 f}{\partial x^2}+\frac{\partial^2 f}{\partial y^2}=0',...
                            '$'];
                app.LabelLaplaceEDP.Text = LaplaceEq;
                app.LabelLaplaceEDP.FontColor = "k";
                app.LabelLaplaceEDP.FontSize = 14;
                if isHarmonica(app,f)
                    textoHarmonica = "A função é harmónica";
                    corHarmonica = "g";
                else
                    textoHarmonica = "A função não é harmónica";
                    corHarmonica = "r";
                end
                app.LabelHarmonica.Text = textoHarmonica;
                app.LabelHarmonica.FontColor = corHarmonica;
            catch Me
                errordlg(Me.message,"ERRO","modal")
            end
        end

        % Button pushed function: ExecutarButton_2
        function ExecutarButton_2Pushed(app, event)
            syms y(x)
            strFx = app.eFx.Value;
            f=@(x) eval(vectorize(strFx));
            a = app.eA_2.Value; h = app.eH.Value; b = app.eB_2.Value;

            if( b < a)
                errordlg("Introduza b > a", "Erro","modal")
                app.EditField.BackgroundColor = [1,0,0]; %[r,g,b]
                app.EditField.FontColor = "w";
                app.eB.BackgroundColor = [1,0,0]; %[r,g,b]
                app.eB.FontColor = "w";
                erroFuncao = MException('MATLAB:invalidInput', 'Introduza b > a');
                throw(erroFuncao)
            else

                app.eB.BackgroundColor = "w";
                app.eB.FontColor = [0,0,0];
            end


            escolhaddMetodos = app.DerivadaDropDown.Value;

            if(escolhaddMetodos == "Progressiva 2 pontos")
                dydxSym = diff(f(x));
                c =@(x) eval(vectorize(char(dydxSym)));
                [x,y,dydxP2] = NDerivadaDFP(app,f,a,b,h);
                dydxExata = c(x);
                erroP2 = abs(dydxExata -dydxP2);
                dydxExata = zeros(size(x)); % Inicializa um vetor de zeros do mesmo tamanho que x para armazenar os valores calculados de dy/dx

                for i = 1:length(x) % Loop para percorrer os elementos de x
                    dydxExata(i) = c(x(i)); % Calcula o valor de dy/dx para x e armazena o resultado em dydxExata
                end

                % calcular o valor de dy/dx para cada elemento em x usando a função c e armazena os valores em dydxExata.
                % resultado final é um vetor dydxExata que contém os valores calculados dy/dx correspondentes a cada elemento em x.

                tabela = array2table([x.',y.',dydxExata.', dydxP2.',erroP2.']);
                app.UITable.Data = tabela;
                plot(app.UIAxes2,x,y,"-r+")
                hold(app.UIAxes2,"on")
                plot(app.UIAxes2,x, dydxExata, "-bo")
                plot(app.UIAxes2,x,dydxP2,"-g*")
                hold(app.UIAxes2,"off")
                nomeColunas = {'x','Exata','ExataDerivada','Progressiva 2','Erro'};
                legendaGrafico = ["Exata","Exata Derivada","Progressiva 2"];
                formaLatex =['$','\frac{dy}{dx}=',latex(dydxSym),'$'];
                cla(app.UIAxes)
                text(app.UIAxes3,0.1,0.5,formaLatex,"Interpreter","latex","FontSize",18)
            end
            if(escolhaddMetodos == "Regressiva 2 pontos")
                dydxSym = diff(f(x));
                c =@(x) eval(vectorize(char(dydxSym)));
                [x,y,dydxR2] = NDerivacaoR2(f,a,b,h);
                dydxExata = c(x);
                erroR2 = abs(dydxExata -dydxR2);
                dydxExata = zeros(size(x)); 
                for i = 1:length(x) 
                    dydxExata(i) = c(x(i)); 
                end                
                tabela = array2table([x.',y.',dydxExata.', dydxR2.',erroR2.']);
                app.UITable.Data = tabela;
                plot(app.UIAxes2,x,y,"-r+")
                hold(app.UIAxes2,"on")
                plot(app.UIAxes2,x, dydxExata, "-bo")
                plot(app.UIAxes2,x,dydxR2,"-g*")
                hold(app.UIAxes2,"off")
                nomeColunas = {'x','Exata','ExataDerivada','Regressiva 2','Erro'};
                legendaGrafico = ["Exata","Exata Derivada","Regressiva 2"];
                formaLatex =['$','\frac{dy}{dx}=',latex(dydxSym),'$'];
                cla(app.UIAxes3)
                text(app.UIAxes3,0.1,0.5,formaLatex,"Interpreter","latex","FontSize",18)
            end
            if(escolhaddMetodos == "Progressiva 3 pontos")
                dydxSym = diff(f(x));
                c =@(x) eval(vectorize(char(dydxSym)));
                [x,y,dydxP3] = NDerivacaoP3(f,a,b,h);
                dydxExata = c(x);
                erroP3 = abs(dydxExata -dydxP3);
                dydxExata = zeros(size(x)); 

                for i = 1:length(x) 
                    dydxExata(i) = c(x(i)); 
                end

               
                tabela = array2table([x.',y.',dydxExata.', dydxP3.',erroP3.']);
                app.UITable.Data = tabela;
                plot(app.UIAxes2,x,y,"-r+")
                hold(app.UIAxes2,"on")
                plot(app.UIAxes2,x, dydxExata, "-bo")
                plot(app.UIAxes2,x,dydxP3,"-g*")
                hold(app.UIAxes,"off")
                nomeColunas = {'x','Exata','ExataDerivada','Progressiva 3','Erro'};
                legendaGrafico = ["Exata","Exata Derivada","Progressiva 3"];
                formaLatex =['$','\frac{dy}{dx}=',latex(dydxSym),'$'];
                cla(app.UIAxes3)      
                text(app.UIAxes3,0.1,0.5,formaLatex,"Interpreter","latex","FontSize",18)
            end
            if(escolhaddMetodos == "Regressiva 3 pontos")
                dydxSym = diff(f(x));
                c =@(x) eval(vectorize(char(dydxSym)));
                [x,y,dydxR3] = NDerivacaoR3(f,a,b,h);
                dydxExata = c(x);
                erroR3 = abs(dydxExata -dydxR3);
                dydxExata = zeros(size(x)); 
                for i = 1:length(x) 
                    dydxExata(i) = c(x(i)); 
                end
                tabela = array2table([x.',y.',dydxExata.', dydxR3.',erroR3.']);
                app.UITable.Data = tabela;
                plot(app.UIAxes2,x,y,"-r+")
                hold(app.UIAxes2,"on")
                plot(app.UIAxes2,x, dydxExata, "-bo")
                plot(app.UIAxes2,x,dydxR3,"-g*")
                hold(app.UIAxes,"off")
                nomeColunas = {'x','Exata','ExataDerivada','Regressiva 3','Erro'};
                legendaGrafico = ["Exata","Exata Derivada","Regressiva 3"];
                formaLatex =['$','\frac{dy}{dx}=',latex(dydxSym),'$'];
                cla(app.UIAxes3)
                text(app.UIAxes3,0.1,0.5,formaLatex,"Interpreter","latex","FontSize",18)
            end
            if(escolhaddMetodos == "Centrada 3 pontos")
                dydxSym = diff(f(x));
                c =@(x) eval(vectorize(char(dydxSym)));
                [x,y,dydxC3] = NDerivacaoC3(f,a,b,h);
                dydxExata = c(x);
                erroC3 = abs(dydxExata -dydxC3);
                dydxExata = zeros(size(x)); 
                for i = 1:length(x) 
                    dydxExata(i) = c(x(i));
                end
                tabela = array2table([x.',y.',dydxExata.', dydxC3.',erroC3.']);
                app.UITable.Data = tabela;
                plot(app.UIAxes2,x,y,"-r+")
                hold(app.UIAxes2,"on")
                plot(app.UIAxes2,x, dydxExata, "-bo")
                plot(app.UIAxes2,x,dydxC3,"-g*")
                hold(app.UIAxes2,"off")
                nomeColunas = {'x','Exata','ExataDerivada','Centrada 3','Erro'};
                legendaGrafico = ["Exata","Exata Derivada","Centrada 3"];
                formaLatex =['$','\frac{dy}{dx}=',latex(dydxSym),'$'];
                cla(app.UIAxes3)               
                text(app.UIAxes3,0.1,0.5,formaLatex,"Interpreter","latex","FontSize",18)
            end
            if(escolhaddMetodos == "2ª derivada 3 pontos")
                dydxSym = diff(f(x));
                c =@(x) eval(vectorize(char(dydxSym)));
                [x,y,dydxD2] = NDerivacaoD2(f,a,b,h);
                dydxExata = c(x);
                erroD2 = abs(dydxExata -dydxD2);
                dydxExata = zeros(size(x)); 
                for i = 1:length(x) 
                    dydxExata(i) = c(x(i)); 
                end                
                tabela = array2table([x.',y.',dydxExata.', dydxD2.',erroD2.']);
                app.UITable.Data = tabela;
                plot(app.UIAxes2,x,y,"-r+")
                hold(app.UIAxes2,"on")
                plot(app.UIAxes2,x, dydxExata, "-bo")
                plot(app.UIAxes2,x,dydxD2,"-g*")
                hold(app.UIAxes2,"off")
                nomeColunas = {'x','Exata','ExataDerivada','2ªDerivada 3','Erro'};
                legendaGrafico = ["Exata","Exata Derivada","2ª Derivada"];
                formaLatex =['$','\frac{dy}{dx}=',latex(dydxSym),'$'];
                cla(app.UIAxes3)
                text(app.UIAxes3,0.1,0.5,formaLatex,"Interpreter","latex","FontSize",18)
            end

            app.UITable.ColumnName = nomeColunas;
            legend(app.UIAxes,legendaGrafico);


            try

            catch ME
                errordlg(ME.message,"ERRO","modal")
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.AbrirRelatrioMenu = uimenu(app.Menu);
            app.AbrirRelatrioMenu.Text = 'Abrir Relatório';
            app.SairMenu = uimenu(app.Menu);
            app.SairMenu.Text = 'Sair';
            app.AutoresMenu = uimenu(app.UIFigure);
            app.AutoresMenu.Text = 'Autores';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.yfxTab = uitab(app.TabGroup);
            app.yfxTab.Title = 'y=f(x)';
            app.UIAxes2 = uiaxes(app.yfxTab);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.Panel_2 = uipanel(app.yfxTab);
            app.UIAxes3 = uiaxes(app.Panel_2);
            app.UIAxes3.XTick = [];
            app.UIAxes3.YTick = [];
            app.UIAxes3.ZTick = [];
            app.fxEditFieldLabel = uilabel(app.Panel_2);
            app.fxEditFieldLabel.Text = 'f(x) =';
            app.eFx = uieditfield(app.Panel_2, 'text');
            app.eFx.Value = 'sin(x)';
            app.xLabel_2 = uilabel(app.Panel_2);
            app.xLabel_2.Text = 'x =';
            app.aEditFieldLabel_7 = uilabel(app.Panel_2);
            app.aEditFieldLabel_7.Text = 'a';
            app.eA_2 = uieditfield(app.Panel_2, 'numeric');
            app.Label_5 = uilabel(app.Panel_2);
            app.Label_5.Text = ':';
            app.aEditFieldLabel_8 = uilabel(app.Panel_2);
            app.aEditFieldLabel_8.Text = 'h';
            app.eH = uieditfield(app.Panel_2, 'numeric');
            app.eH.Value = 0.2;
            app.Label_6 = uilabel(app.Panel_2);
            app.Label_6.Text = ':';
            app.aEditFieldLabel_9 = uilabel(app.Panel_2);
            app.aEditFieldLabel_9.Text = 'b';
            app.eB_2 = uieditfield(app.Panel_2, 'numeric');
            app.eB_2.Value = 7;
            app.ExecutarButton_2 = uibutton(app.Panel_2, 'push');
            app.ExecutarButton_2.ButtonPushedFcn = createCallbackFcn(app, @ExecutarButton_2Pushed, true);
            app.ExecutarButton_2.Text = 'Executar';
            app.ExportarGrficoButton = uibutton(app.Panel_2, 'push');
            app.ExportarGrficoButton.Text = 'Exportar Gráfico';
            app.ExportarTabelaButton = uibutton(app.Panel_2, 'push');
            app.ExportarTabelaButton.Text = 'Exportar Tabela';
            app.DerivadaDropDownLabel = uilabel(app.Panel_2);
            app.DerivadaDropDownLabel.Text = 'Derivada :';
            app.DerivadaDropDown = uidropdown(app.Panel_2);
            app.DerivadaDropDown.Items = {'Progressiva 2 pontos', 'Regressiva 2 pontos', 'Progressiva 3 pontos', 'Regressiva 3 pontos', 'Centrada 3 pontos', '2ª derivada 3 pontos'};
            app.DerivadaDropDown.Value = 'Progressiva 2 pontos';
            app.UITable = uitable(app.yfxTab);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.zfxyTab = uitab(app.TabGroup);
            app.zfxyTab.Title = 'z=f(x,y)';
            app.UIAxes = uiaxes(app.zfxyTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Panel = uipanel(app.zfxyTab);
            app.fxyEditFieldLabel = uilabel(app.Panel);
            app.fxyEditFieldLabel.Text = 'f(x,y) =';
            app.eFxy = uieditfield(app.Panel, 'text');
            app.eFxy.Value = 'x^2+y^2';
            app.aEditFieldLabel = uilabel(app.Panel);
            app.aEditFieldLabel.Text = 'a';
            app.eA = uieditfield(app.Panel, 'numeric');
            app.eA.Value = -2;
            app.aEditFieldLabel_2 = uilabel(app.Panel);
            app.aEditFieldLabel_2.Text = 'h1';
            app.eH1 = uieditfield(app.Panel, 'numeric');
            app.eH1.Value = 0.2;
            app.eB = uieditfield(app.Panel, 'numeric');
            app.eB.Value = 2;
            app.aEditFieldLabel_3 = uilabel(app.Panel);
            app.aEditFieldLabel_3.Text = 'b';
            app.xLabel = uilabel(app.Panel);
            app.xLabel.Text = 'x =';
            app.Label = uilabel(app.Panel);
            app.Label.Text = ':';
            app.Label_2 = uilabel(app.Panel);
            app.Label_2.Text = ':';
            app.yLabel = uilabel(app.Panel);
            app.yLabel.Text = 'y =';
            app.cLabel = uilabel(app.Panel);
            app.cLabel.Text = 'c';
            app.eC = uieditfield(app.Panel, 'numeric');
            app.eC.Value = -2;
            app.Label_3 = uilabel(app.Panel);
            app.Label_3.Text = ':';
            app.aEditFieldLabel_5 = uilabel(app.Panel);
            app.aEditFieldLabel_5.Text = 'h2';
            app.eH2 = uieditfield(app.Panel, 'numeric');
            app.eH2.Value = 0.2;
            app.Label_4 = uilabel(app.Panel);
            app.Label_4.Text = ':';
            app.aEditFieldLabel_6 = uilabel(app.Panel);
            app.aEditFieldLabel_6.Text = 'd';
            app.eD = uieditfield(app.Panel, 'numeric');
            app.eD.Value = 2;
            app.ExecutarButton = uibutton(app.Panel, 'push');
            app.ExecutarButton.ButtonPushedFcn = createCallbackFcn(app, @ExecutarButtonPushed, true);
            app.ExecutarButton.Text = 'Executar';
            app.Panel_3 = uipanel(app.Panel);
            app.LabelHarmonica = uilabel(app.Panel_3);
            app.LabelHarmonica.Text = 'A função não é harmónica';
            app.LabelLaplaceEDP = uilabel(app.Panel_3);
            app.LabelLaplaceEDP.Interpreter = 'latex';
            app.LabelLaplaceEDP.Text = 'EDP_eqLaplace';
            app.IntegraoNumricaTab = uitab(app.TabGroup);
            app.IntegraoNumricaTab.Title = 'Integração Numérica';
            app.EditFieldLabel = uilabel(app.IntegraoNumricaTab);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.IntegraoNumricaTab, 'numeric');
            app.IntegraoNumricaListBoxLabel = uilabel(app.IntegraoNumricaTab);
            app.IntegraoNumricaListBoxLabel.Text = 'Integração Numérica';
            app.IntegraoNumricaListBox = uilistbox(app.IntegraoNumricaTab);
            app.IntegraoNumricaListBox.Items = {'Regra dos Trapézios', 'Regra de Simpson'};
            app.IntegraoNumricaListBox.Value = 'Regra dos Trapézios';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Maquina03bCDI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end