classdef Maquina01MNED < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        GridLayout         matlab.ui.container.GridLayout
        LeftPanel          matlab.ui.container.Panel
        DadosdoPVPanel     matlab.ui.container.Panel
        eF                 matlab.ui.control.EditField
        ftyEditFieldLabel  matlab.ui.control.Label
        nSpinner           matlab.ui.control.Spinner
        MSpinnerLabel      matlab.ui.control.Label
        eA                 matlab.ui.control.NumericEditField
        y0EditFieldLabel   matlab.ui.control.Label
        eB                 matlab.ui.control.NumericEditField
        aEditFieldLabel    matlab.ui.control.Label
        bEditFieldLabel    matlab.ui.control.Label
        eY0                matlab.ui.control.NumericEditField
        ButtonGroup        matlab.ui.container.ButtonGroup
        RK2Button          matlab.ui.control.ToggleButton
        EulerMButton       matlab.ui.control.ToggleButton
        ResetButton        matlab.ui.control.Button
        ExecutarButton     matlab.ui.control.Button
        RightPanel         matlab.ui.container.Panel
        Panel_2            matlab.ui.container.Panel
        UITable            matlab.ui.control.Table
        Panel              matlab.ui.container.Panel
        UIAxes             matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ExecutarButton
        function ExecutarButtonPushed(app, event)
        syms y(t);
        
        strF = app.eF.Value;
        f=@(t,y) eval(vectorize(strF));
        a = app.eA.Value;
        b = app.eB.Value;
        n = app.nSpinner.Value;
        y0 = app.eY0.Value;

        try
            sExata = dsolve(diff(y,t)==f(t,y),y(a)==y0);
            %char(sExata)
            g =@(t) eval(vectorize(char(sExata)));

            [t,yEuler] = NEuler(f,a,b,n,y0);
            [~,yRK2] = NRK2(f,a,b,n,y0);

            yExata = g(t);
            erroEuler = abs(yExata-yEuler);
            erroRK2 = abs(yExata-yRK2);

            A = [t.',yExata.',yEuler.',yRK2.',erroEuler.',erroRK2.'];
            tabelaResultados = array2table(A,"VariableNames", ...
                ["t","Exata","Euler","RK2","erroEuler","erroRK2"]);
            disp(tabelaResultados)

            plot(app.UIAxes,t,yExata,"-ro")
            hold (app.UIAxes,"on");
            plot(app.UIAxes,t,yEuler,"-g*")
            plot(app.UIAxes,t,yRK2,"-b+")
            hold (app.UIAxes,"off")
            grid (app.UIAxes, "on");
            legend(app.UIAxes,"Exata","Euler","RK2")
            app.UITable.Data = tabelaResultados;

        catch Me
          %disp(upper("Ocorreu um erro na chamada das funções e/ou display de resultados"))
          %errordlg("Ocorreu um erro","ERRO","modal")
          errordlg(Me.message,"ERRO","modal")
        end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {444, 444};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ExecutarButton = uibutton(app.LeftPanel, 'push');
            app.ExecutarButton.ButtonPushedFcn = createCallbackFcn(app, @ExecutarButtonPushed, true);
            app.ExecutarButton.Text = 'Executar';
            app.ResetButton = uibutton(app.LeftPanel, 'push');
            app.ResetButton.Text = 'Reset';
            app.DadosdoPVPanel = uipanel(app.LeftPanel);
            app.DadosdoPVPanel.Title = 'Dados do PV';
            app.ButtonGroup = uibuttongroup(app.DadosdoPVPanel);
            app.ButtonGroup.Title = 'Button Group';
            app.EulerMButton = uitogglebutton(app.ButtonGroup);
            app.EulerMButton.Text = 'EulerM';
            app.EulerMButton.Value = true;
            app.RK2Button = uitogglebutton(app.ButtonGroup);
            app.RK2Button.Text = 'RK2';
            app.eY0 = uieditfield(app.DadosdoPVPanel, 'numeric');
            app.bEditFieldLabel = uilabel(app.DadosdoPVPanel);
            app.bEditFieldLabel.Text = 'b';
            app.aEditFieldLabel = uilabel(app.DadosdoPVPanel);
            app.aEditFieldLabel.Text = 'a';
            app.eB = uieditfield(app.DadosdoPVPanel, 'numeric');
            app.eB.Value = 1.5;
            app.y0EditFieldLabel = uilabel(app.DadosdoPVPanel);
            app.y0EditFieldLabel.Text = 'y0';
            app.eA = uieditfield(app.DadosdoPVPanel, 'numeric');
            app.eA.Value = 1;
            app.MSpinnerLabel = uilabel(app.DadosdoPVPanel);
            app.MSpinnerLabel.Text = 'M';
            app.nSpinner = uispinner(app.DadosdoPVPanel);
            app.nSpinner.Limits = [2 Inf];
            app.nSpinner.Value = 2;
            app.ftyEditFieldLabel = uilabel(app.DadosdoPVPanel);
            app.ftyEditFieldLabel.Text = 'f(t,y) = ';
            app.eF = uieditfield(app.DadosdoPVPanel, 'text');
            app.eF.Value = 'y+exp(3*t)';
            app.RightPanel = uipanel(app.GridLayout);
            app.Panel = uipanel(app.RightPanel);
            app.UIAxes = uiaxes(app.Panel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Panel_2 = uipanel(app.RightPanel);
            app.UITable = uitable(app.Panel_2);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Maquina01MNED

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end