classdef Maquina01MNED < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        GridLayout           matlab.ui.container.GridLayout
        LeftPanel            matlab.ui.container.Panel
        ResetButton          matlab.ui.control.Button
        ExecutarButton       matlab.ui.control.Button
        bgMNumericos         matlab.ui.container.ButtonGroup
        TodososMtodosButton  matlab.ui.control.ToggleButton
        OutroButton          matlab.ui.control.ToggleButton
        ODE45Button          matlab.ui.control.ToggleButton
        RK4Button            matlab.ui.control.ToggleButton
        RK2Button            matlab.ui.control.ToggleButton
        EulerMButton         matlab.ui.control.ToggleButton
        EulerButton          matlab.ui.control.ToggleButton
        DadosdoPVIPanel      matlab.ui.container.Panel
        eY0                  matlab.ui.control.NumericEditField
        aEditFieldLabel_3    matlab.ui.control.Label
        nSpinner             matlab.ui.control.Spinner
        nSpinnerLabel        matlab.ui.control.Label
        aEditFieldLabel_2    matlab.ui.control.Label
        eB                   matlab.ui.control.NumericEditField
        eA                   matlab.ui.control.NumericEditField
        aEditFieldLabel      matlab.ui.control.Label
        eF                   matlab.ui.control.EditField
        ftyEditFieldLabel    matlab.ui.control.Label
        RightPanel           matlab.ui.container.Panel
        GridLayout2          matlab.ui.container.GridLayout
        UITable              matlab.ui.control.Table
        UIAxes               matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            ExecutarButtonPushed(app,[]);
        end

        % Callback function: ExecutarButton, nSpinner
        function ExecutarButtonPushed(app, event)
            syms y(t)
            strF = app.eF.Value;
            f=@(t,y) eval(vectorize(strF));
            a = app.eA.Value;
            b = app.eB.Value;
            n = app.nSpinner.Value;
            y0= app.eY0.Value;


            MetNumericoSelecionado = app.bgMNumericos.SelectedObject.Text;


            try
                sExata = dsolve(diff(y,t)==f(t,y),y(a)==y0);
                g =@(t) eval(vectorize(char(sExata)));
                
                [t,yEuler] = NEuler(f,a,b,n,y0);
                [~,yRK2] = NRK2(f,a,b,n,y0);
                %[~,yRK4] = NRK4(f,a,b,n,y0);
                %ODE45
                %OUTRO

                yExata = g(t);
                erroEuler = abs(yExata-yEuler);
                erroRK2 = abs(yExata-yRK2);
                %erroRK4
                %erroODE
                %erroOutro
             
                plot(app.UIAxes,t,yExata,"-ro")
                hold(app.UIAxes,"on")
                
                switch MetNumericoSelecionado
                    case "Euler"
                        plot(app.UIAxes,t,yEuler,"-g*")
                        legendaGrafico = ["Exata", "Euler"];
                        A = [t.',yExata.',yEuler.'];
                        nomeColunas = ["t", "yExata","yEuler","erroEuler"];

                    case "EulerM"
                        warndlg("não implementado","aviso","modal");

                    case "RK2"
                        plot(app.UIAxes,t,yRK2,"-b+")
                        legendaGrafico = ["Exata", "RK2"];
                        A = [t.',yExata.',yRK2.'];
                        nomeColunas = ["t", "yExata","yRK2","erroRK2"];

                    case "RK4"
                        warndlg("não implementado","aviso","modal");

                    case "ODE45"
                        warndlg("não implementado","aviso","modal");

                    case "Outro"
                        warndlg("não implementado","aviso","modal");

                    case "Todos os Métodos"
                        plot(app.UIAxes,t,yEuler,"-g*")
                        plot(app.UIAxes,t,yRK2,"-b+")
                        %plot(app.UIAxes,t,yRK4,"-ro")
                        %plot(app.UIAxes,t,yODE45,"")
                        %plot(app.UIAxes,t,yOutro,"")
                        legendaGrafico = ["Exata", "Euler", "RK2"]; %,"RK4", "ODE45", "Outro"];
                        A = [t.',yExata.',yEuler.',yRK2.',erroEuler.',erroRK2.']; %,yRK4.', yODE45.', yOutro.', errroRK4.', erroODE45.', erroOutro.'];
                        nomeColunas = ["t","Exata", "Euler", "RK2","erroEuler","erroRK2"]; %,"RK4", "ODE45", "Outro", "erroRK4", "erroODE45", "erroOutro"];
                end

                legend(app.UIAxes,legendaGrafico)
                grid(app.UIAxes,"on")
                hold(app.UIAxes,"off")
                tabelaResultados = array2table(A);
                app.UITable.Data = tabelaResultados;
                app.UITable.ColumnName = nomeColunas;
            
            catch Me
                errordlg(Me.message,"ERRO","modal")
            end

        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {377, 377};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.DadosdoPVIPanel = uipanel(app.LeftPanel);
            app.DadosdoPVIPanel.Title = 'Dados do PVI';
            app.ftyEditFieldLabel = uilabel(app.DadosdoPVIPanel);
            app.ftyEditFieldLabel.Text = 'f(t,y) =';
            app.eF = uieditfield(app.DadosdoPVIPanel, 'text');
            app.eF.Value = 'y+exp(3*t)';
            app.aEditFieldLabel = uilabel(app.DadosdoPVIPanel);
            app.aEditFieldLabel.Text = 'a';
            app.eA = uieditfield(app.DadosdoPVIPanel, 'numeric');
            app.eA.Value = 1;
            app.eB = uieditfield(app.DadosdoPVIPanel, 'numeric');
            app.eB.Value = 1.5;
            app.aEditFieldLabel_2 = uilabel(app.DadosdoPVIPanel);
            app.aEditFieldLabel_2.Text = 'b';
            app.nSpinnerLabel = uilabel(app.DadosdoPVIPanel);
            app.nSpinnerLabel.Text = 'n';
            app.nSpinner = uispinner(app.DadosdoPVIPanel);
            app.nSpinner.Limits = [1 Inf];
            app.nSpinner.ValueDisplayFormat = '%.0f';
            app.nSpinner.ValueChangedFcn = createCallbackFcn(app, @ExecutarButtonPushed, true);
            app.nSpinner.Value = 1;
            app.aEditFieldLabel_3 = uilabel(app.DadosdoPVIPanel);
            app.aEditFieldLabel_3.Text = 'y0';
            app.eY0 = uieditfield(app.DadosdoPVIPanel, 'numeric');
            app.eY0.Value = 2;
            app.bgMNumericos = uibuttongroup(app.LeftPanel);
            app.bgMNumericos.Title = 'Métodos Numéricos';
            app.EulerButton = uitogglebutton(app.bgMNumericos);
            app.EulerButton.Text = 'Euler';
            app.EulerMButton = uitogglebutton(app.bgMNumericos);
            app.EulerMButton.Text = 'EulerM';
            app.RK2Button = uitogglebutton(app.bgMNumericos);
            app.RK2Button.Text = 'RK2';
            app.RK4Button = uitogglebutton(app.bgMNumericos);
            app.RK4Button.Text = 'RK4';
            app.ODE45Button = uitogglebutton(app.bgMNumericos);
            app.ODE45Button.Text = 'ODE45';
            app.OutroButton = uitogglebutton(app.bgMNumericos);
            app.OutroButton.Text = 'Outro';
            app.TodososMtodosButton = uitogglebutton(app.bgMNumericos);
            app.TodososMtodosButton.Text = 'Todos os Métodos';
            app.TodososMtodosButton.Value = true;
            app.ExecutarButton = uibutton(app.LeftPanel, 'push');
            app.ExecutarButton.ButtonPushedFcn = createCallbackFcn(app, @ExecutarButtonPushed, true);
            app.ExecutarButton.Text = 'Executar';
            app.ResetButton = uibutton(app.LeftPanel, 'push');
            app.ResetButton.Text = 'Reset';
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.RightPanel);
            app.UIAxes = uiaxes(app.GridLayout2);
            xlabel(app.UIAxes, 't')
            ylabel(app.UIAxes, 'y')
            app.UITable = uitable(app.GridLayout2);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Maquina01MNED

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end