classdef MaquinaCDI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        TabGroup           matlab.ui.container.TabGroup
        zfxyTab            matlab.ui.container.Tab
        Panel              matlab.ui.container.Panel
        Executar           matlab.ui.control.Button
        yLabel             matlab.ui.control.Label
        xLabel             matlab.ui.control.Label
        eH2                matlab.ui.control.NumericEditField
        h2EditFieldLabel   matlab.ui.control.Label
        eH1                matlab.ui.control.NumericEditField
        h1EditFieldLabel   matlab.ui.control.Label
        eD                 matlab.ui.control.NumericEditField
        dEditFieldLabel    matlab.ui.control.Label
        eC                 matlab.ui.control.NumericEditField
        cEditFieldLabel    matlab.ui.control.Label
        eB                 matlab.ui.control.NumericEditField
        bEditFieldLabel    matlab.ui.control.Label
        eA                 matlab.ui.control.NumericEditField
        aEditFieldLabel    matlab.ui.control.Label
        eFxy               matlab.ui.control.EditField
        fxyEditFieldLabel  matlab.ui.control.Label
        UIAxes             matlab.ui.control.UIAxes
        Tab2               matlab.ui.container.Tab
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Executar
        function ExecutarPushed(app, event)
            syms z(x,y);
            strF = app.eFxy.Value;
            f = @(x,y) eval(vectorize(strF));
            
            a = app.eA.Value;
            h1 = app.eH1.Value;
            b= app.eB.Value;

            c = app.eC.Value;
            h2 = app.eH2.Value;
            d = app.eD.Value;

            erroFxy = MException("MATLAB:Executar:badF","Introduza uma função em x e y");
            % outras excecoes: a<b, h2 tem de ser menor que a distancia
            % entre a e b. c<d e h2 tem de ser menor que a distancia entre
            % c e d.

            try
                try
                    z = f(x,y); %aqui ainda são simbólicos
                    app.eFxy.BackgroundColor = "w";
                catch
                    app.eFxy.BackgroundColor = "r";
                    throw(erroFxy)
                end
                [x,y] =  meshgrid(a:h1:b,c:h2:d);
                z = f(x,y); %aqui já têm valores numéricos
                meshc(app.UIAxes,x,y,z);
            catch Me 
                errordlg(Me.message,"ERRO","modal")
            end
           
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.zfxyTab = uitab(app.TabGroup);
            app.zfxyTab.Title = 'z=f(x,y)';
            app.UIAxes = uiaxes(app.zfxyTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Panel = uipanel(app.zfxyTab);
            app.fxyEditFieldLabel = uilabel(app.Panel);
            app.fxyEditFieldLabel.Text = 'f(x,y) = ';
            app.eFxy = uieditfield(app.Panel, 'text');
            app.eFxy.Value = 'x^2+y^2';
            app.aEditFieldLabel = uilabel(app.Panel);
            app.aEditFieldLabel.Text = 'a';
            app.eA = uieditfield(app.Panel, 'numeric');
            app.eA.Value = -2;
            app.bEditFieldLabel = uilabel(app.Panel);
            app.bEditFieldLabel.Text = 'b';
            app.eB = uieditfield(app.Panel, 'numeric');
            app.eB.Value = 2;
            app.cEditFieldLabel = uilabel(app.Panel);
            app.cEditFieldLabel.Text = 'c';
            app.eC = uieditfield(app.Panel, 'numeric');
            app.eC.Value = -2;
            app.dEditFieldLabel = uilabel(app.Panel);
            app.dEditFieldLabel.Text = 'd';
            app.eD = uieditfield(app.Panel, 'numeric');
            app.eD.Value = 2;
            app.h1EditFieldLabel = uilabel(app.Panel);
            app.h1EditFieldLabel.Text = 'h1';
            app.eH1 = uieditfield(app.Panel, 'numeric');
            app.eH1.Value = 0.2;
            app.h2EditFieldLabel = uilabel(app.Panel);
            app.h2EditFieldLabel.Text = {'h2'; ''};
            app.eH2 = uieditfield(app.Panel, 'numeric');
            app.eH2.Value = 0.2;
            app.xLabel = uilabel(app.Panel);
            app.xLabel.Text = 'x   = ';
            app.yLabel = uilabel(app.Panel);
            app.yLabel.Text = 'y   =';
            app.Executar = uibutton(app.Panel, 'push');
            app.Executar.ButtonPushedFcn = createCallbackFcn(app, @ExecutarPushed, true);
            app.Executar.Text = 'Executar';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MaquinaCDI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end