classdef MaquinaMNSED < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        FileMenu                     matlab.ui.container.Menu
        SairMenu                     matlab.ui.container.Menu
        AutoresMenu                  matlab.ui.container.Menu
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        ddSDinamico                  matlab.ui.control.DropDown
        SistemadinmicoDropDownLabel  matlab.ui.control.Label
        bAtualizar_2                 matlab.ui.control.Button
        bAtualizar                   matlab.ui.control.Button
        bgMetodos                    matlab.ui.container.ButtonGroup
        rbTodos                      matlab.ui.control.RadioButton
        rbRK4                        matlab.ui.control.RadioButton
        rbRK2                        matlab.ui.control.RadioButton
        rbEulerM                     matlab.ui.control.RadioButton
        rbEuler                      matlab.ui.control.RadioButton
        DadosdoPVIPanel              matlab.ui.container.Panel
        eV0                          matlab.ui.control.NumericEditField
        aEditFieldLabel_5            matlab.ui.control.Label
        eG                           matlab.ui.control.EditField
        gtuvLabel                    matlab.ui.control.Label
        eU0                          matlab.ui.control.NumericEditField
        aEditFieldLabel_4            matlab.ui.control.Label
        eN                           matlab.ui.control.NumericEditField
        aEditFieldLabel_3            matlab.ui.control.Label
        eB                           matlab.ui.control.NumericEditField
        aEditFieldLabel_2            matlab.ui.control.Label
        eA                           matlab.ui.control.NumericEditField
        aEditFieldLabel              matlab.ui.control.Label
        eF                           matlab.ui.control.EditField
        ftuvLabel                    matlab.ui.control.Label
        RightPanel                   matlab.ui.container.Panel
        UIAxes                       matlab.ui.control.UIAxes
        UITable                      matlab.ui.control.Table
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            bAtualizarButtonPushed(app, [])
        end

        % Button pushed function: bAtualizar
        function bAtualizarButtonPushed(app, event)
            cla(app.UIAxes)
            syms u(t) v(t)
            strF = app.eF.Value;
            f = @(t,u,v) eval(vectorize(strF));
            strG = app.eG.Value;
            g = @(t,u,v) eval(vectorize(strG));  

            a = app.eA.Value;
            b = app.eB.Value;
            n = app.eN.Value;
            u0 = app.eU0.Value;
            v0 = app.eV0.Value;
 
            escolhabgMetodos = app.bgMetodos.SelectedObject;
            rbMetodoSelecionado = find([app.rbEuler,...
                                        app.rbEulerM,...
                                        app.rbRK2,...
                                        app.rbRK4,...
                                        app.rbTodos]==escolhabgMetodos);
 
            erroFuncao = MException("Matlab:bAtualizar:badF",...
                                    "Introduza uma função em t, u e v" );
 
            erroB = MException("Matlab:bAtualizar:badB",...
                                    "Introduza um b > a" );
            %outras
 
            try
                try
                    z = g(t,u,v);
                    app.eF.BackgroundColor = "w";
 
                catch ME
                    app.eF.BackgroundColor = "r";
                    throw(erroFuncao)
                end
 
                if ~(isreal(b) && b > a)
                    app.eB.BackgroundColor = "r";
                    throw(erroB)
                else
                     app.eB.BackgroundColor = "w";
 
                end
 
                eqns = [diff(u,t)==v,diff(v,t)==-16*u];
                ci = [u(0)==u0,v(0)==v0];
                [sExataU,~] = dsolve(eqns,ci);
                
                h =@(t) eval(vectorize(char(sExataU)));
                
                
                [t,uEuler,~]  = NEulerSED(f,g,a,b,n,u0,v0);
                [~,uEulerM,~] = NEulerMSED(f,g,a,b,n,u0,v0);
                [~,uRK2,~]    = NRK2SED(f,g,a,b,n,u0,v0);
                [~,uRK4,~]    = NRK4SED(f,g,a,b,n,u0,v0);
                
                uExata = h(t);
                
                erroEuler  = abs(uExata-uEuler);
                erroEulerM = abs(uExata-uEulerM);
                erroRK2    = abs(uExata-uRK2);
                erroRK4    = abs(uExata-uRK4);
 
                plot(app.UIAxes,t,uExata,"-+r")
                hold(app.UIAxes,"on")     
 
                switch rbMetodoSelecionado
                    case 1
                        plot(app.UIAxes,t,uEuler,"-g")
                        matrizResultados = [t.', uExata.', uEuler.',erroEuler.']; 
                        legendaGrafico = ["Exata","Euler"];
                        NomeColunasTabela = ["t","Exata","Euler","ErroEuler"];
                    case 2
                        plot(app.UIAxes,t,uEulerM,"-m")
                        matrizResultados = [t.', uExata.', uEulerM.',erroEulerM.']; 
                        legendaGrafico = ["Exata","Euler Melhorado"];
                        NomeColunasTabela = ["t","Exata","Euler Melhorado","ErroEulerM"];
                    case 3
                        plot(app.UIAxes,t,uRK2,"-b")
                        matrizResultados = [t.', uExata.', uRK2.',erroRK2.']; 
                        legendaGrafico = ["Exata","RK2"];
                        NomeColunasTabela = ["t","Exata","RK2","ErroRK2"]; 
                    case 4
                        plot(app.UIAxes,t,uRK4,"-g")
                        matrizResultados = [t.', uExata.', uRK4.',erroRK4.']; 
                        legendaGrafico = ["Exata","RK4"];
                        NomeColunasTabela = ["t","Exata","RK4","ErroRK4"];                     
                    case 5
                        plot(app.UIAxes,t,uEuler,"-g")
                        plot(app.UIAxes,t,uEulerM,"-k")
                        plot(app.UIAxes,t,uRK2,"-b")                        
                        plot(app.UIAxes,t,uRK4,"-m") 
                        matrizResultados = [t.', uExata.', uEuler.'...
                            ,uEulerM.',uRK2.',uRK4.',erroEuler.',erroRK2.'...
                            ,erroRK4.',erroEulerM.']; 
                        legendaGrafico = ["Exata","Euler","EulerM","RK2","RK4"];
                        NomeColunasTabela = ["t","Exata","Euler","EulerM", "RK2",...
                            "RK4","ErroEuler","ErroEulerM","erroRK2","erroRK4"];                        
 
                end
 
                grid(app.UIAxes,"on")
                legend(app.UIAxes,legendaGrafico)
 
                app.UITable.Data = array2table(matrizResultados);
                app.UITable.ColumnName = NomeColunasTabela;
 
            catch ME
                errordlg(ME.message,"ERRO","modal")
            end
        end

        % Menu selected function: SairMenu
        function SairMenuSelected(app, event)
            SN = questdlg("Quer sair?","Sair","Sim","Não","Sim");
            if strcmp(SN,"Sim")
                delete(app.UIFigure)
            end
        end

        % Menu selected function: AutoresMenu
        function AutoresMenuSelected(app, event)
            autor
        end

        % Value changed function: ddSDinamico
        function ddSDinamicoValueChanged(app, event)
            value = app.ddSDinamico.Value;
            switch value
                case "1"
                    strG = "-16*u";
                    a = 0; b = 4; n = 100;
                    u0 = 9; v0 = 0;
                case "2"
                    strG = "16*u"; %alterar
                    a = 0; b = 5; n = 100;
                    u0 = 9; v0 = 0;
                case "3"
                    strG = "-32*u"; %alterar
                    a = 0; b = 4; n = 100;
                    u0 = 9; v0 = 0;
                case "4"
                    strG = "u"; %alterar
                    a = 0; b = 4; n = 100;
                    u0 = 9; v0 = 0;
            end

            app.eG.Value = strG;
            app.eA.Value = a;
            app.eB.Value = b;
            app.eN.Value = n;
            app.eU0.Value = u0;
            app.eV0.Value = v0;

            bAtualizarButtonPushed(app, event)

        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {430, 430};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.SairMenu = uimenu(app.FileMenu);
            app.SairMenu.MenuSelectedFcn = createCallbackFcn(app, @SairMenuSelected, true);
            app.SairMenu.Text = 'Sair';
            app.AutoresMenu = uimenu(app.UIFigure);
            app.AutoresMenu.MenuSelectedFcn = createCallbackFcn(app, @AutoresMenuSelected, true);
            app.AutoresMenu.Text = 'Autores';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.DadosdoPVIPanel = uipanel(app.LeftPanel);
            app.DadosdoPVIPanel.Title = 'Dados do PVI';
            app.ftuvLabel = uilabel(app.DadosdoPVIPanel);
            app.ftuvLabel.Text = 'f(t,u,v) = ';
            app.eF = uieditfield(app.DadosdoPVIPanel, 'text');
            app.eF.Editable = 'off';
            app.eF.Value = 'v';
            app.aEditFieldLabel = uilabel(app.DadosdoPVIPanel);
            app.aEditFieldLabel.Text = 'a';
            app.eA = uieditfield(app.DadosdoPVIPanel, 'numeric');
            app.aEditFieldLabel_2 = uilabel(app.DadosdoPVIPanel);
            app.aEditFieldLabel_2.Text = 'b';
            app.eB = uieditfield(app.DadosdoPVIPanel, 'numeric');
            app.eB.Value = 15;
            app.aEditFieldLabel_3 = uilabel(app.DadosdoPVIPanel);
            app.aEditFieldLabel_3.Text = 'n';
            app.eN = uieditfield(app.DadosdoPVIPanel, 'numeric');
            app.eN.Value = 100;
            app.aEditFieldLabel_4 = uilabel(app.DadosdoPVIPanel);
            app.aEditFieldLabel_4.Text = 'u0 =';
            app.eU0 = uieditfield(app.DadosdoPVIPanel, 'numeric');
            app.eU0.Value = 9;
            app.gtuvLabel = uilabel(app.DadosdoPVIPanel);
            app.gtuvLabel.Text = 'g(t,u,v) = ';
            app.eG = uieditfield(app.DadosdoPVIPanel, 'text');
            app.eG.Value = '-16*u';
            app.aEditFieldLabel_5 = uilabel(app.DadosdoPVIPanel);
            app.aEditFieldLabel_5.Text = 'v0 =';
            app.eV0 = uieditfield(app.DadosdoPVIPanel, 'numeric');
            app.bgMetodos = uibuttongroup(app.LeftPanel);
            app.bgMetodos.Title = 'Métodos Numéricos';
            app.rbEuler = uiradiobutton(app.bgMetodos);
            app.rbEuler.Text = 'Euler';
            app.rbEuler.Value = true;
            app.rbEulerM = uiradiobutton(app.bgMetodos);
            app.rbEulerM.Text = 'Euler Melhorado';
            app.rbRK2 = uiradiobutton(app.bgMetodos);
            app.rbRK2.Text = 'RK2';
            app.rbRK4 = uiradiobutton(app.bgMetodos);
            app.rbRK4.Text = 'RK4';
            app.rbTodos = uiradiobutton(app.bgMetodos);
            app.rbTodos.Text = 'Todos os Métodos';
            app.bAtualizar = uibutton(app.LeftPanel, 'push');
            app.bAtualizar.ButtonPushedFcn = createCallbackFcn(app, @bAtualizarButtonPushed, true);
            app.bAtualizar.Text = 'Atualizar';
            app.bAtualizar_2 = uibutton(app.LeftPanel, 'push');
            app.bAtualizar_2.Text = 'Reset';
            app.SistemadinmicoDropDownLabel = uilabel(app.LeftPanel);
            app.SistemadinmicoDropDownLabel.Text = 'Sistema dinâmico';
            app.ddSDinamico = uidropdown(app.LeftPanel);
            app.ddSDinamico.Items = {'Mola massa sem amortecimento', 'Mola Massa com amortecimento', 'Option 3', 'Option 4'};
            app.ddSDinamico.ItemsData = {'1', '2', '3', '4'};
            app.ddSDinamico.ValueChangedFcn = createCallbackFcn(app, @ddSDinamicoValueChanged, true);
            app.ddSDinamico.Value = '1';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            xlabel(app.UIAxes, 't')
            ylabel(app.UIAxes, 'u')
            zlabel(app.UIAxes, 'Z')
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MaquinaMNSED

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end